classdef TARDIS_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TARDISTemporalAnalysisofRelativeDistancesUIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ResetRunButton                  matlab.ui.control.Button
        makeJDs                         matlab.ui.control.Button
        RunTARDISButton                 matlab.ui.control.Button
        SettingsPanel                   matlab.ui.container.Panel
        FolderLocationFinder            matlab.ui.control.Button
        FileLocationFinder              matlab.ui.control.Button
        DataLocationEditField           matlab.ui.control.EditField
        DataLocationEditFieldLabel      matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        BasicTab                        matlab.ui.container.Tab
        CustomPDFsettingsPanel          matlab.ui.container.Panel
        CustomPDF_UpperboundsEditField  matlab.ui.control.EditField
        UpperboundsEditFieldLabel       matlab.ui.control.Label
        CustomPDF_LowerboundsEditField  matlab.ui.control.EditField
        LowerboundsEditFieldLabel       matlab.ui.control.Label
        CustomPDF_StartValuesEditField  matlab.ui.control.EditField
        StartValuesEditFieldLabel       matlab.ui.control.Label
        CustomPDFDropDown               matlab.ui.control.DropDown
        PDFLabel                        matlab.ui.control.Label
        GuessfromdataButton             matlab.ui.control.Button
        LocalizationuncertaintyEditField  matlab.ui.control.NumericEditField
        LocalizationuncertaintyEditFieldLabel  matlab.ui.control.Label
        UnitLabel2_3                    matlab.ui.control.Label
        aDDAsettingsPanel               matlab.ui.container.Panel
        UnitLabel1_anaddarad            matlab.ui.control.Label
        UnitLabel1_anaddalen            matlab.ui.control.Label
        LengthEditField                 matlab.ui.control.NumericEditField
        LengthEditFieldLabel            matlab.ui.control.Label
        DiameterEditField               matlab.ui.control.NumericEditField
        DiameterEditFieldLabel          matlab.ui.control.Label
        CellularconfinementCheckBox     matlab.ui.control.CheckBox
        NrofspeciesEditField            matlab.ui.control.NumericEditField
        NrofspeciesEditFieldLabel       matlab.ui.control.Label
        FittingmethodButtonGroup        matlab.ui.container.ButtonGroup
        customPDFButton                 matlab.ui.control.RadioButton
        NoFitting                       matlab.ui.control.RadioButton
        AutoChoosePop                   matlab.ui.control.RadioButton
        FitwbleachCheckBox              matlab.ui.control.CheckBox
        pop_fixRatiosCheckBox           matlab.ui.control.CheckBox
        aDDAButton                      matlab.ui.control.RadioButton
        TwoPopButton                    matlab.ui.control.RadioButton
        OnePopButton                    matlab.ui.control.RadioButton
        UnitLabel2_2                    matlab.ui.control.Label
        LongesttracklengthEditField     matlab.ui.control.NumericEditField
        LongesttracklengthEditFieldLabel  matlab.ui.control.Label
        UnitLabel2                      matlab.ui.control.Label
        FrametimeEditField              matlab.ui.control.NumericEditField
        FrametimeEditFieldLabel         matlab.ui.control.Label
        MaxtSpinner                     matlab.ui.control.Spinner
        MaxtSpinnerLabel                matlab.ui.control.Label
        UnitLabel1                      matlab.ui.control.Label
        MaximumJumpDistanceEditField    matlab.ui.control.NumericEditField
        MaximumJumpDistanceEditField_2Label  matlab.ui.control.Label
        BGsubtractionaccuracySlider     matlab.ui.control.Slider
        BGsubtractionaccuracySliderLabel  matlab.ui.control.Label
        ExpertTab                       matlab.ui.container.Tab
        SwiftParam_noiseDensityEditField  matlab.ui.control.NumericEditField
        Noisedensitylocum2frameLabel    matlab.ui.control.Label
        FittinglocalizationuncertaintyCheckBox  matlab.ui.control.CheckBox
        ExperimentalLabel               matlab.ui.control.Label
        StrobocorrectionButtonGroup     matlab.ui.container.ButtonGroup
        msLabel                         matlab.ui.control.Label
        ExcitationpulseDurationField    matlab.ui.control.EditField
        ExcitationpulseButton           matlab.ui.control.RadioButton
        FullframeexcitationButton       matlab.ui.control.RadioButton
        NostrobocorrectionButton        matlab.ui.control.RadioButton
        OthersettingsLabel              matlab.ui.control.Label
        FittingconstraintsLabel         matlab.ui.control.Label
        StartLabel                      matlab.ui.control.Label
        UpperLabel                      matlab.ui.control.Label
        LowerLabel                      matlab.ui.control.Label
        ub_addaEditField                matlab.ui.control.EditField
        lb_addaEditField                matlab.ui.control.EditField
        ub_2popEditField                matlab.ui.control.EditField
        lb_2popEditField                matlab.ui.control.EditField
        ub_1popEditField                matlab.ui.control.EditField
        lb_1popEditField                matlab.ui.control.EditField
        EditFieldLabel                  matlab.ui.control.Label
        BGsubtractioninterpolationDropDown  matlab.ui.control.DropDown
        BGsubtractioninterpolationDropDownLabel  matlab.ui.control.Label
        StartpointBGratioofmaxJDEditField  matlab.ui.control.NumericEditField
        StartpointBGratioofmaxJDEditFieldLabel  matlab.ui.control.Label
        NrofBGbinsEditField             matlab.ui.control.NumericEditField
        NrofBGbinsEditFieldLabel        matlab.ui.control.Label
        VerboseCheckBox                 matlab.ui.control.CheckBox
        PerformEstimationFitCheckBox    matlab.ui.control.CheckBox
        aDDAEditField                   matlab.ui.control.EditField
        aDDAEditFieldLabel              matlab.ui.control.Label
        popEditField_2                  matlab.ui.control.EditField
        popEditField_2Label             matlab.ui.control.Label
        popEditField                    matlab.ui.control.EditField
        popEditFieldLabel               matlab.ui.control.Label
        RuninfoTab                      matlab.ui.container.Tab
        TextArea                        matlab.ui.control.TextArea
        LocFilterTab                    matlab.ui.container.Tab
        Filter5EditField                matlab.ui.control.EditField
        Filter5EditFieldLabel           matlab.ui.control.Label
        Filter5ValEditField             matlab.ui.control.NumericEditField
        Filter5Sign                     matlab.ui.control.DropDown
        Filter4EditField                matlab.ui.control.EditField
        Filter4EditFieldLabel           matlab.ui.control.Label
        Filter4ValEditField             matlab.ui.control.NumericEditField
        Filter4Sign                     matlab.ui.control.DropDown
        Filter3EditField                matlab.ui.control.EditField
        Filter3EditFieldLabel           matlab.ui.control.Label
        Filter3ValEditField             matlab.ui.control.NumericEditField
        Filter3Sign                     matlab.ui.control.DropDown
        Filter2EditField                matlab.ui.control.EditField
        Filter2EditFieldLabel           matlab.ui.control.Label
        Filter2ValEditField             matlab.ui.control.NumericEditField
        Filter2Sign                     matlab.ui.control.DropDown
        Filter1ValEditField             matlab.ui.control.NumericEditField
        Filter1Sign                     matlab.ui.control.DropDown
        Filter1EditField                matlab.ui.control.EditField
        Filter1EditFieldLabel           matlab.ui.control.Label
        StorageVisualTab                matlab.ui.container.Tab
        StoreMHTparametersCheckBox      matlab.ui.control.CheckBox
        VisualisealsoinseparatewindowCheckBox  matlab.ui.control.CheckBox
        StoreswiftparametersCheckBox    matlab.ui.control.CheckBox
        OpenstoragefolderButton         matlab.ui.control.Button
        StoreMATfitdatarecommendedCheckBox  matlab.ui.control.CheckBox
        StorealldataNOTrecommendedverybigfilesizeCheckBox  matlab.ui.control.CheckBox
        StoreJSONfitdatarecommendedCheckBox  matlab.ui.control.CheckBox
        StoreJDhistogramimageCheckBox   matlab.ui.control.CheckBox
        StoreintermediatefitimageCheckBox  matlab.ui.control.CheckBox
        StorefinalfitimageCheckBox      matlab.ui.control.CheckBox
        FolderLocationFinder_vis        matlab.ui.control.Button
        FileLocationFinder_vis          matlab.ui.control.Button
        ShowlocalizationsButton         matlab.ui.control.Button
        VisualiseoldresultsButton       matlab.ui.control.Button
        AnalysedDataLocationEditField   matlab.ui.control.EditField
        AnalysedDataLocationEditFieldLabel  matlab.ui.control.Label
        VisualisationscalingButtonGroup  matlab.ui.container.ButtonGroup
        LogarithmicVisButton            matlab.ui.control.RadioButton
        LinearVisButton                 matlab.ui.control.RadioButton
        RightPanel                      matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        FullresultsTab                  matlab.ui.container.Tab
        Hyperlink2_2                    matlab.ui.control.Hyperlink
        Hyperlink2                      matlab.ui.control.Hyperlink
        IfyoufoundTARDISusefullpleaseciteusMartensetalLabel  matlab.ui.control.Label
        Hyperlink                       matlab.ui.control.Hyperlink
        ResultsPanel                    matlab.ui.container.Panel
        RightBotPanel                   matlab.ui.container.Panel
        ResultAxis                      matlab.ui.control.UIAxes
        EstimationresultsTab            matlab.ui.container.Tab
        RightTopPanel                   matlab.ui.container.Panel
        JumpdistancehistogramsTab       matlab.ui.container.Tab
        JDhistPanel                     matlab.ui.container.Panel
        LocalizationsTab                matlab.ui.container.Tab
        LocAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Data % Used as general data storage throughout the app
    end
    
    methods (Access = private)
        function storeUIdata(app)
            UIvars = getSaveNameList(app);
            for i = 1:size(UIvars)
                eval(['UIvars{i,3} = ' UIvars{i,1} '.' UIvars{i,2} ';']);
            end
            [directory,~,~] = fileparts(mfilename('fullpath'));
            save([directory '\UI\UIVariables.mat'],"UIvars");
        end
        
        function results = getSaveNameList(app)
            results = {,...
                'app.DataLocationEditField','Value';...
                'app.BGsubtractionaccuracySlider','Value';...
                'app.LongesttracklengthEditField','Value';...
                'app.MaxtSpinner','Value';...
                'app.FrametimeEditField','Value';...
                'app.MaximumJumpDistanceEditField','Value';...
                'app.aDDAButton','Value';...
                'app.TwoPopButton','Value';...
                'app.OnePopButton','Value';...
                'app.customPDFButton','Value';...
                'app.aDDAsettingsPanel','Visible';...
                'app.LengthEditField','Value';...
                'app.DiameterEditField','Value';...
                'app.LengthEditField','Visible';...
                'app.DiameterEditField','Visible';...
                'app.LengthEditFieldLabel','Visible';...
                'app.DiameterEditFieldLabel','Visible';...
                'app.UnitLabel1_anaddalen','Visible';...
                'app.UnitLabel1_anaddarad','Visible';...
                'app.CellularconfinementCheckBox','Value';...
                'app.NrofspeciesEditField','Value';...
                'app.OnePopButton','Value';...
                'app.LocalizationuncertaintyEditField','Value';...
                'app.PerformEstimationFitCheckBox','Value';...
                'app.VerboseCheckBox','Value';...
                'app.NrofBGbinsEditField','Value';...
                'app.LogarithmicVisButton','Value';...
                'app.LinearVisButton','Value';...
                'app.StartpointBGratioofmaxJDEditField','Value';...
                'app.popEditField','Value';...
                'app.popEditField_2','Value';...
                'app.aDDAEditField','Value';...
                'app.AnalysedDataLocationEditField','Value';...
                'app.AnalysedDataLocationEditField','Value';...
                'app.BGsubtractioninterpolationDropDown','Value';...
                'app.StorealldataNOTrecommendedverybigfilesizeCheckBox','Value';...
                'app.StoreMATfitdatarecommendedCheckBox','Value';...
                'app.StoreJSONfitdatarecommendedCheckBox','Value';...
                'app.StoreJDhistogramimageCheckBox','Value';...
                'app.StoreintermediatefitimageCheckBox','Value';...
                'app.StorefinalfitimageCheckBox','Value';...
                'app.pop_fixRatiosCheckBox','Value';...
                'app.FitwbleachCheckBox','Value';...
                'app.lb_1popEditField','Value';...
                'app.ub_1popEditField','Value';...
                'app.lb_2popEditField','Value';...
                'app.ub_2popEditField','Value';...
                'app.lb_addaEditField','Value';...
                'app.ub_addaEditField','Value';...
                'app.AutoChoosePop','Value';...
                'app.NoFitting','Value';...
                'app.Filter1EditField','Value';...
                'app.Filter1ValEditField','Value';...
                'app.Filter1Sign','Value';...
                'app.Filter2EditField','Value';...
                'app.Filter2ValEditField','Value';...
                'app.Filter2Sign','Value';...
                'app.Filter3EditField','Value';...
                'app.Filter3ValEditField','Value';...
                'app.Filter3Sign','Value';...
                'app.Filter4EditField','Value';...
                'app.Filter4ValEditField','Value';...
                'app.Filter4Sign','Value';...
                'app.Filter5EditField','Value';...
                'app.Filter5ValEditField','Value';...
                'app.Filter5Sign','Value';...
                'app.ExcitationpulseButton','Value';...
                'app.FullframeexcitationButton','Value';...
                'app.NostrobocorrectionButton','Value';...
                'app.msLabel','Enable';...
                'app.ExcitationpulseDurationField','Enable';...
                'app.ExcitationpulseDurationField','Value';...
                'app.FittinglocalizationuncertaintyCheckBox','Value';...
                'app.StoreswiftparametersCheckBox','Value';...
                'app.StoreMHTparametersCheckBox','Value';...
                'app.VisualisealsoinseparatewindowCheckBox','Value';...
                'app.SwiftParam_noiseDensityEditField','Value';...
                'app.CustomPDFDropDown','Value';...
                'app.CustomPDF_UpperboundsEditField','Value';...
                'app.CustomPDF_LowerboundsEditField','Value';...
                'app.CustomPDF_StartValuesEditField','Value';...
                'app.CustomPDFsettingsPanel','Visible';...
                };
        end
        
        function loadUIdata(app)
            [directory,~,~] = fileparts(mfilename('fullpath'));
            try
                load([directory '\UI\UIVariables.mat'],"UIvars");
                for i = 1:size(UIvars)
                    eval([UIvars{i,1} '.' UIvars{i,2} ' = UIvars{i,3};']);
                end
            catch
                %UI variables does not yet exists, but no worries, just
                %save the current data should work?
                storeUIdata(app);
            end
        end
        
        function drawHOfits(app)
        end
        
        function ShowResults(app,appdata)
            if appdata.settingsURDA.customPDF == 0
                if appdata.settingsURDA.populations > 0 %regular fits
                if appdata.populationsOut == 1
                    RowTitle = {'Pop1'};    
                    DCI = appdata.HOFFfit(1) - appdata.HOFFfitCI(1,1);
                    Dtext = {[num2str(round(appdata.HOFFfit(1),2)) ' ± ' num2str(round(DCI,3))]};
                    if app.Data.settingsURDA.fitWithBleach
                        ColTitle = {'DiffCoeff (μm^2/s)','Bleach half-time (s)'};
                        Dbl = appdata.HOFFfit(3) - appdata.HOFFfitCI(1,3);
                        Bltext = {[num2str(round(appdata.HOFFfit(3),3))  ' ± '  num2str(round(Dbl,3))]};
                        T = table(Dtext,Bltext,'VariableNames',ColTitle,'RowNames',RowTitle);
                        cw = 149;
                    else
                        ColTitle = {'DiffCoeff'};
                        T = table(Dtext,'VariableNames',ColTitle,'RowNames',RowTitle);
                        cw = 300;
                    end
                elseif appdata.populationsOut == 2
                    RowTitle = {'Pop1';'Pop2'};
                    DCI1 = appdata.HOFFfit(1) - appdata.HOFFfitCI(1,1);
                    DCI2 = appdata.HOFFfit(2) - appdata.HOFFfitCI(1,2);
                    Dtext = {[num2str(round(appdata.HOFFfit(1),2)) ' ± ' num2str(round(DCI1,3))];...
                        [num2str(round(appdata.HOFFfit(2),2)) ' ± ' num2str(round(DCI2,3))]};
                    F1 = 1/(1+appdata.HOFFfit(3));
                    CIv = appdata.HOFFfitCI(1,3);
                    FCI1 = abs(F1 - 1/(1+appdata.HOFFfitCI(1,3)));
                    Ftext = {[num2str(round(F1,2)) ' ± ' num2str(round(FCI1,3))];...
                        [num2str(round(1-F1,2)) ' ± ' num2str(round(FCI1,3))]};
    
                    if app.Data.settingsURDA.fitWithBleach
                        ColTitle = {'DiffCoeff (μm^2/s)','Fraction','Bleach half-time (s)'};
                        Dbl1 = appdata.HOFFfit(5) - appdata.HOFFfitCI(1,5);
                        if app.Data.settingsURDA.fixRatios2pop
                            Dbl2 = Dbl1;
                            Bltext = {[num2str(round(appdata.HOFFfit(5),3))  ' ± '  num2str(round(Dbl1,3))];...
                            [num2str(round(appdata.HOFFfit(5),3))  ' ± '  num2str(round(Dbl2,3))]};
                        else
                            Dbl2 = appdata.HOFFfit(6) - appdata.HOFFfitCI(1,6);
                            Bltext = {[num2str(round(appdata.HOFFfit(5),3))  ' ± '  num2str(round(Dbl1,3))];...
                            [num2str(round(appdata.HOFFfit(6),3))  ' ± '  num2str(round(Dbl2,3))]};
                        end
                        T = table(Dtext,Ftext,Bltext,'VariableNames',ColTitle,'RowNames',RowTitle);
                        cw = 149;
                    else
                        ColTitle = {'DiffCoeff (μm^2/s)','Fraction'};
                        T = table(Dtext,Ftext,'VariableNames',ColTitle,'RowNames',RowTitle);
                        cw = 149;
                    end
                end
                else %anaDDA fit
                    konvCI = appdata.HOFFfit(1) - appdata.HOFFfitCI(1,1);
                    koffvCI = appdata.HOFFfit(2) - appdata.HOFFfitCI(1,2);
                    DvCI = appdata.HOFFfit(3) - appdata.HOFFfitCI(1,3);
                    RowTitle = {'Pop'};
                    ColTitle = {'k_off','k_on','DiffCoeff (μm^2/s)'};
                    konv = {[num2str(round(appdata.HOFFfit(1),1)) ' ± ' num2str(round(konvCI,1))]};
                    koffv = {[num2str(round(appdata.HOFFfit(2),1)) ' ± ' num2str(round(koffvCI,1))]};
                    Dv = {[num2str(round(appdata.HOFFfit(3),1)) ' ± ' num2str(round(DvCI,2))]};
                    T = table(konv,koffv,Dv,'VariableNames',ColTitle,'RowNames',RowTitle);
                    cw = 149;
                end
            else %Custom PDF
                RowTitle = {'CustomPDF'};
                if appdata.settingsURDA.fitWithBleach
                    nrparams = size(appdata.HOFFfit,2)-2;
                else
                    nrparams = size(appdata.HOFFfit,2)-max(appdata.settingsURDA.dt_arr);
                end
                ColTitle = {};
                paramtext = {};
                for p = 1:nrparams
                    paramCI = appdata.HOFFfit(p) - appdata.HOFFfitCI(1,p);
                    paramtext{end+1} = [num2str(round(appdata.HOFFfit(p),2)) ' ± ' num2str(round(paramCI,3))];
                    ColTitle{end+1} = ['Param_' num2str(p)];
                end
                if app.Data.settingsURDA.fitWithBleach
                    ColTitle{end+1} = 'Bleach_half_time_s';
                    Dbl = appdata.HOFFfit(end) - appdata.HOFFfitCI(1,end);
                    Bltext = {[num2str(round(appdata.HOFFfit(end),3))  ' ± '  num2str(round(Dbl,3))]};
                    paramtext{end+1} = [num2str(round(appdata.HOFFfit(end),3))  ' ± '  num2str(round(Dbl,3))];
                    T = table;
                    for p = 1:size(ColTitle,2)
                        eval(['T.' ColTitle{p} ' = paramtext{p}']);
                    end
                    T.Properties.RowNames = RowTitle;
                    cw = 149;
                else
                    ColTitle = {'DiffCoeff'};
                    T = table(paramtext,'VariableNames',ColTitle,'RowNames',RowTitle);
                    cw = 300;
                end
            end
        t = uitable('Data',T,'Units', 'Normalized', 'Position',[0.0, 0.0, 1, 1],'parent',app.ResultsPanel);
        t.ColumnWidth = {cw,cw,cw};
        t.FontSize = 12;
        end
        
        function changeBGsubtractnr(app)
            nrframes = app.BGsubtractionaccuracySlider.Value/100;
            maxframesever = 50;
            nrframes = round(10^(nrframes*log10(maxframesever)));
%             app.TextArea.Value = [['Nr frames set to ' num2str(nrframes)]; app.TextArea.Value ];
            app.Data.settingsURDA.frame_dist_BG = [app.Data.settingsURDA.frame_dist_BG_start+1:1:app.Data.settingsURDA.frame_dist_BG_start+nrframes-1];
        end
        
        function SaveFigure(app,LocAxes,SaveName)
            h = figure;
            h.Visible = 'off';
            x = UIAxes.XAxis.Parent.Children.XData;
            y = UIAxes.XAxis.Parent.Children.YData;
            plot(x,y)
            lgndName1 = UIAxes.Legend.String{1};
            lgd = legend(lgndName1);
            lgd.Box = UIAxes.Legend.Box;
            lgd.Location = UIAxes.Legend.Location;
            h.CurrentAxes.YLabel.String = UIAxes.YLabel.String;
            h.CurrentAxes.YLabel.FontSize = UIAxes.YLabel.FontSize;
            h.CurrentAxes.XLabel.String = UIAxes.XLabel.String;
            h.CurrentAxes.XLabel.FontSize = UIAxes.XLabel.FontSize;
            h.CurrentAxes.Title.String = UIAxes.Title.String;
            h.CurrentAxes.Title.FontSize = UIAxes.Title.FontSize;
            h.CurrentAxes.XLim = [0 max(x)];
            h.CurrentAxes.XLim = [0 max(y)+1];
            saveas(h,SaveName,'jpg')
            savefig(h,SaveName)
            delete(h)
        end
        
        function ClearResults(app)
            %Clear the results table
            uitable('Data',[],'parent',app.ResultsPanel,'Units', 'Normalized', 'Position',[-1, 0 ,2 ,1]);
        end
        
        function clearResultPlots(app)
            %Function to clear the result-plots
            
            %Clear plots
            app.RightPanel.AutoResizeChildren = 'off';
            %Delete old visualisations
            delete(app.RightTopPanel.Children);
            %Delete all childs of RightBotPanel, except those that have
            %ResultAxis as Tag
            for ch = size(app.RightBotPanel.Children,1):-1:1
                Child = app.RightBotPanel.Children(ch);
                if ~strcmp(Child.Tag,'ResultAxis')
                    delete(Child);
                end
            end
            

            %Clear JD plots
            app.JDhistPanel.AutoResizeChildren = 'off';
            %Delete old visualisations
            delete(app.JDhistPanel.Children);

            %Clear written results
            ClearResults(app);
                        
            %Update what's being drawn
            drawnow;
        end
        
        function visualiseResults(app)
%             try
                %Visualise the results in app
                %First clear previous result plots
                clearResultPlots(app);
                
                %Show FF (fullfit results)
                if app.Data.settingsURDA.customPDF == 0
                    Visualisation_FF(app,'RightBotPanel',app.Data.Visualisation_FF_outputCell);
        
        %             Show HO (first fit results)
                    Visualisation_HO_BGsubtract(app,'RightTopPanel',app.Data.Visualisation_HO_outputCell.bgbinningnr,app.Data.Visualisation_HO_outputCell.dt_arr,app.Data.Visualisation_HO_outputCell.JDarrBG,app.Data.Visualisation_HO_outputCell.maxdist,app.Data.Visualisation_HO_outputCell.minlogpoint,app.Data.Visualisation_HO_outputCell.linorlogBGsubtract,app.Data.Visualisation_HO_outputCell.bgarr,app.Data.Visualisation_HO_outputCell.JDarrSignalCell,...
                    app.Data.Visualisation_HO_outputCell.signalCurve_interp,app.Data.Visualisation_HO_outputCell.stepsizearraytrue,app.Data.Visualisation_HO_outputCell.bgratios,app.Data.Visualisation_HO_outputCell.startpointBG,app);
                    
        %             Show JDs
                    visualiseJD(app)
        
                    %Show written results
                    ShowResults(app,app.Data);
                else %Custom PDF
                    Visualisation_FF_customPDF(app,'RightBotPanel',app.Data.Visualisation_FF_outputCell);
        %             Show JDs
                    visualiseJD(app)
                    %Show written results
                    ShowResults(app,app.Data);
                end
                %Set app.TabGroup2 to full results
                app.TabGroup2.SelectedTab = app.FullresultsTab;
%             catch
%                 disp('Visualisation unsuccesfull, probably a NaN or something!')
%             end
        end
        
        function saveData(app,savename)            
            %Additionally: save Swift JSON info
            if app.Data.settingsURDA.StoreSWIFTparameters
                %store values as .json with the correct name/folder
                js = jsonencode(app.Data.SwiftParams,"ConvertInfAndNaN",false,'PrettyPrint',true);
                save([savename(1:end-4) '_swiftparams.json'],'js');
                fid=fopen([savename(1:end-4) '_swiftparams.json'],'w');
                fprintf(fid, js);
                fclose(fid);
            end      
            %Additionally: save MHT .XML info
            if app.Data.settingsURDA.StoreMHTparameters
                % Create a Document Object Model (DOM) object
                docNode = com.mathworks.xml.XMLUtils.createDocument('root');
                root = docNode.getDocumentElement;
            
                % Create MHTconfiguration element
                MHTconfiguration = docNode.createElement('MHTconfiguration');
            
                % Create detectionInput element and set attributes
                detectionInput = docNode.createElement('detectionInput');
                detectionInput.setAttribute('detectionRate', num2str(app.Data.MHTParams.detectionRate));
                detectionInput.setAttribute('detectionSource', '');
                detectionInput.setAttribute('numberFalseDetection', num2str(app.Data.MHTParams.totLocNoise_perFrame));
            
                % Append detectionInput to MHTconfiguration
                MHTconfiguration.appendChild(detectionInput);
            
                % Create targetExistence element and set attributes
                targetExistence = docNode.createElement('targetExistence');
                targetExistence.setAttribute('confirmationThreshold', '0.5');
                targetExistence.setAttribute('terminationThreshold', '1.0E-4');
                targetExistence.setAttribute('trackLength', num2str(app.Data.MHTParams.trackLength));
            
                % Append targetExistence to MHTconfiguration
                MHTconfiguration.appendChild(targetExistence);
            
                % Create motionModel element and set attributes
                motionModel = docNode.createElement('motionModel');
                motionModel.setAttribute('isDirectedMotion_1', 'false');
                motionModel.setAttribute('isSingleMotion', 'true');
                motionModel.setAttribute('updateMotion_1', 'false');
                motionModel.setAttribute('yxDisplacement_1', num2str(app.Data.MHTParams.medianJumpDistance*1e9/200)); %NOTE: assuming a 100 nm pixel size here
                motionModel.setAttribute('zDisplacement_1', '3');
            
                % Append motionModel to MHTconfiguration
                MHTconfiguration.appendChild(motionModel);
            
                % Create mht element and set attributes
                mht = docNode.createElement('mht');
                mht.setAttribute('gateFactor', '4');
                mht.setAttribute('mhtDepth', '4');
                mht.setAttribute('numberNewObjects', num2str(app.Data.MHTParams.totTrackSignal_perFrame));
                mht.setAttribute('numberObjectsFirstFrame', num2str(app.Data.MHTParams.totTrackSignal_perFrame));
            
                % Append mht to MHTconfiguration
                MHTconfiguration.appendChild(mht);
            
                % Create ouput element and set attribute
                output = docNode.createElement('ouput');
                output.setAttribute('trackGroupName', 'mhtTracks-TARDIS-priors');
            
                % Append output to MHTconfiguration
                MHTconfiguration.appendChild(output);
            
                % Append MHTconfiguration to the root element
                root.appendChild(MHTconfiguration);
            
                % Write the XML document to a file
                xmlFileName = [savename(1:end-4) '_MHTparams.xml'];
                xmlwrite(xmlFileName, docNode);
            end
            %In most cases - when not the JDs-only-button is pressed
            if ~app.Data.settingsURDA.createJDsonly
                %Things to save: URDA settings, final fit, images of fits, json
                %with info, JD information, nr of linkages
                appinfo = app.Data;
                HOFFfit = app.Data.HOFFfit;
                HOFFfitCI = app.Data.HOFFfitCI;
                try
                    timeDuration = app.Data.timeDuration;
                catch
                    timeDuration = 0;
                end
                paramEsts = app.Data.paramEsts;
                HOfitCI = app.Data.HOfitCI;
                JDinformation = app.Data.JDonlydata{1};
                %Get nr of linkages at every dt
                LinkageNumber = zeros(size(app.Data.Visualisation_FF_outputCell.JDarrSignalCell));
                for dt = 1:size(app.Data.Visualisation_FF_outputCell.JDarrSignalCell,2)
                    LinkageNumber(dt) = size(app.Data.Visualisation_FF_outputCell.JDarrSignalCell{dt},1);
                end
        
                %Store JSON data
                if app.StoreJSONfitdatarecommendedCheckBox.Value
                    app.TextArea.Value = ['Saving JSON data...'; app.TextArea.Value];
                    %Do some JSON data storage
                    clear s
                    settingsJSON = app.Data.settingsURDA;
                    settingsJSON.callfromUI = [];
                    s.FinalFitResults = app.Data.HOFFfit;
                    s.FinalFitResultsCI = app.Data.HOFFfitCI;
                    s.FirstFitResults = app.Data.paramEsts;
                    s.FirstFitResultsCI = num2cell(app.Data.HOfitCI);
                    s.LinkageNumber = LinkageNumber;
                    s.allSettings = settingsJSON;
                    try
                        s.TimeSpendTotal_ms = app.Data.timeDuration;
                    catch
                        s.TimeSpendTotal_ms = 0;
                    end
                    js = jsonencode(s,"ConvertInfAndNaN",false,'PrettyPrint',true);
                    save([savename(1:end-4) '.json'],'js');
                    fid=fopen([savename(1:end-4) '.json'],'w');
                    fprintf(fid, js);
                    fclose(fid);
                end
    
                %Store MAT info
                %Remove callfromUI as this cannot be saved
                appinfo.settingsURDA.callfromUI = [];
                if app.StoreMATfitdatarecommendedCheckBox.Value
                    app.TextArea.Value = ['Saving MAT data...'; app.TextArea.Value];
                    if app.StorealldataNOTrecommendedverybigfilesizeCheckBox.Value
                        save(savename,'appinfo','HOFFfit','HOFFfitCI','paramEsts','HOfitCI','timeDuration');
                    else
                        %Remove the biggest data-culprits
                        appinfo.Visualisation_HO_outputCell.JDarrBG = [];
                        appinfo.Visualisation_FF_outputCell.JDarrSignalCell = [];
                        appinfo.Visualisation_FF_outputCell.origDataLoc = [];
                        try
                            appinfo.anaDDAvisInfoHO.DlistHO = [];
                            appinfo.anaDDAvisInfoHO.visual = [];
                            appinfo.anaDDAvisInfoFF.DlistFF = [];
                            appinfo.anaDDAvisInfoFF.DlistHO = [];
                            appinfo.anaDDAvisInfoFF.visual = [];
                            appinfo.anaDDAvisInfoFF.visualisationinfo.JDarrSignalCell = [];
                            appinfo.anaDDAvisInfoFF.visualisationinfo.JDarrSignalCell = [];
                            appinfo.Visualisation_FF_outputCell.extraoutput.main.visual = [];
                            appinfo.Visualisation_FF_outputCell.extraoutput.main.DlistFF = [];
                            appinfo.Visualisation_FF_outputCell.extraoutput.main.DlistHO = [];
                            appinfo.Visualisation_FF_outputCell.extraoutput.main.visualisationinfo.JDarrSignalCell = [];
                            appinfo.Visualisation_FF_outputCell.extraoutput.linvis.BGarr_maxdist_D = [];
                        catch ME
    
                        end
                        save(savename,'appinfo','HOFFfit','HOFFfitCI','paramEsts','HOfitCI','JDinformation','timeDuration');
                    end
                end
                
                %Save JD hist figure
                if app.StoreJDhistogramimageCheckBox.Value
                    app.TextArea.Value = ['Saving JD images...'; app.TextArea.Value];
                    try
                    fig = Visualisation_JDs('hidden','',app.Data.JDonlydata{1},app);
                    fsz = 400;
                    fig.Position = [0,0,fsz*size(app.Data.JDonlydata{1},2),fsz*0.75];
                    export_fig([savename(1:end-4) '_JDinformation.png'],fig,'-r600','-transparent');
                    print(fig, '-dsvg', [savename(1:end-4) '_JDinformation.svg']);
                    catch
                        disp('No JD images stored - NaN error or similar')
                    end
                end
    
                %Only when full fit is done and estimation fit is done: store
                %HO image
                if app.StoreintermediatefitimageCheckBox.Value
                    app.TextArea.Value = ['Saving estimation fit images...'; app.TextArea.Value];
                    if app.Data.settingsURDA.createJDsonly == 0
                        if app.PerformEstimationFitCheckBox.Value
                            try
                            %Do some image generation and store data
                            fig = Visualisation_HO_BGsubtract('hidden','',app.Data.Visualisation_HO_outputCell.bgbinningnr,...
                                app.Data.Visualisation_HO_outputCell.dt_arr,app.Data.Visualisation_HO_outputCell.JDarrBG,...
                                app.Data.Visualisation_HO_outputCell.maxdist,app.Data.Visualisation_HO_outputCell.minlogpoint,...
                                app.Data.Visualisation_HO_outputCell.linorlogBGsubtract,app.Data.Visualisation_HO_outputCell.bgarr,...
                                app.Data.Visualisation_HO_outputCell.JDarrSignalCell,...
                                app.Data.Visualisation_HO_outputCell.signalCurve_interp,...
                                app.Data.Visualisation_HO_outputCell.stepsizearraytrue,app.Data.Visualisation_HO_outputCell.bgratios, ...
                                app.Data.Visualisation_HO_outputCell.startpointBG,app);
                
                            fsz = 400;
                            fig.Position = [0,0,fsz*size(app.Data.Visualisation_FF_outputCell.size_dt,2),fsz*0.75];
                            export_fig([savename(1:end-4) '_estimationfit.png'],fig,'-r600','-transparent');
                            print(fig, '-dsvg', [savename(1:end-4) '_estimationfit.svg']);
                            catch
                                app.TextArea.Value = ['No HO images stored - unsure why...'; app.TextArea.Value];
                                disp('No HO images stored - unsure why')
                            end
                        end
                    end
                end  
    
                %Only when full fit is done: save FF figure
                if app.StorefinalfitimageCheckBox.Value
                    app.TextArea.Value = ['Saving final fit images...'; app.TextArea.Value];
                    try
                    if app.Data.settingsURDA.createJDsonly == 0
                        if app.Data.settingsURDA.customPDF == 0
                            %Do some image generation and store data
                            fig = Visualisation_FF('hidden','',app.Data.Visualisation_FF_outputCell);
        
                            fsz = 400;
                            fig.Position = [0,0,fsz*size(app.Data.Visualisation_FF_outputCell.size_dt,2),fsz*0.75];
                            export_fig([savename(1:end-4) '_fullfit.png'],fig,'-r600','-transparent');
                            print(fig, '-dsvg', [savename(1:end-4) '_fullfit.svg']);
                        else
                            fig = Visualisation_FF_customPDF('hidden','',app.Data.Visualisation_FF_outputCell);
                            fsz = 400;
                            fig.Position = [0,0,fsz*size(app.Data.Visualisation_FF_outputCell.size_dt,2),fsz*0.75];
                            export_fig([savename(1:end-4) '_fullfit.png'],fig,'-r600','-transparent');
                            print(fig, '-dsvg', [savename(1:end-4) '_fullfit.svg']);
                        end
                    end
                    catch
                        app.TextArea.Value = ['No FF images stored - unsure why...'; app.TextArea.Value];
                        disp('No FF images stored - unsure why')
                    end
                end
            else
                %Only JD info (app.Data.settingsURDA.createJDsonly == 1)
                appinfo = app.Data;
                appinfo.settingsURDA.callfromUI = [];
                appinfo.Visualisation_HO_outputCell = [];
                JDinformation = app.Data.JDonlydata;
                save(savename,'appinfo','JDinformation');
                app.TextArea.Value = ['Stored JD information only'; app.TextArea.Value];
            end
            app.TextArea.Value = [' '; '-----------------------------------------'; app.TextArea.Value];
            
        end
        
        function curFolder = getCurFolderDataLocEditField(app)
            %Get folder from DataLocationEditField
            try
                %find last value of / or \
                str = app.DataLocationEditField.Value;
                lastvalFolder = 0;
                if max(strfind(str,'\')) > lastvalFolder
                    lastvalFolder = max(strfind(str,'\'));
                end
                if max(strfind(str,'/')) > lastvalFolder
                    lastvalFolder = max(strfind(str,'\'));
                end
                curFolder = app.DataLocationEditField.Value(1:lastvalFolder);
            catch
                %Get current folder backup in case it's not working
                curFolder = cd;
            end
        end
        
        function showLocalizations(app,poslist)
                cla(app.LocAxes);
                scatter(app.LocAxes,poslist(:,2),poslist(:,3),1,'k','filled');
                axis(app.LocAxes,[min(poslist(:,2)) max(poslist(:,2)) min(poslist(:,3)) max(poslist(:,3))]);
                axis(app.LocAxes, 'square');
                axis(app.LocAxes, 'equal');
        end
        
        function poslist = loadDataMATCSV(app,fnm)
            try
                if strcmp(fnm(end-2:end),'mat')
                    try
                        %Transfer data to poslist variable
                        load(fnm,'pos');
                        poslist = [pos zeros(size(pos,1),1)];
                        poslist(poslist(:,1)==0,:)=[];
                    catch
                        try
                        %Transfer data to poslist variable
                        load(fnm,'tracks');
                        pos = tracks(:,[3 1 2]);
                        [~,srt] = sort(pos(:,1));
                        pos = pos(srt,:);
                        poslist = [pos zeros(size(pos,1),1)];
                        poslist(poslist(:,1)==0,:)=[];
                        catch
                            disp('ERROR!')
                        end
                    end
                elseif strcmp(fnm(end-2:end),'csv')
                    [software_id, DECODEpxsize] = determineLocFile(app,fnm);
                    %Get all data and header
                    csvtable = readtable(fnm);
                    %Starting filtering
                    %Loop over all filters
                    try
                        for i = 1:5
                            fieldname = eval(['app.Filter',num2str(i),'EditField.Value']);
                            if ~isempty(fieldname)
                                fieldsign = eval(['app.Filter',num2str(i),'Sign.Value']);
                                fieldval = eval(['app.Filter',num2str(i),'ValEditField.Value']);
                                if contains(fieldname,'_sig') %If filtering on loc_unc of DECODE
                                    if fieldname == 'x_sig'
                                        filtlist = eval(['csvtable.',fieldname,fieldsign,num2str(fieldval/DECODEpxsize(1))]);
                                    elseif fieldname == 'y_sig'
                                        filtlist = eval(['csvtable.',fieldname,fieldsign,num2str(fieldval/DECODEpxsize(2))]);
                                    end
                                else %Filtering on all common occurances
                                    filtlist = eval(['csvtable.',fieldname,fieldsign,num2str(fieldval)]);
                                end
                                csvtable = csvtable(filtlist,:);
                            end
                        end
                    catch
                        app.TextArea.Value = ['Error with filtering localizations, continuing without filtering'; app.TextArea.Value ];
                        disp('ERROR WITH FILTERING LOCALIZATIONS, CONTINUING WITHOUT FILTERING');
%                         keyboard
                    end
                    if software_id == 1
                        %Find the headers we need ('frame', 'x_nm_' and 'y_nm_')
                        poslist = [csvtable.frame csvtable.x_nm_*1e-9 csvtable.y_nm_*1e-9];
                        poslist = [poslist zeros(size(poslist,1),1)];
                        poslist(poslist(:,1)==0,:)=[];
                    elseif software_id == 2
                        %Find the headers we need ('frame', 'x_nm_' and 'y_nm_')
                        poslist = [csvtable.frame_ix+1 csvtable.x*DECODEpxsize(1)*1e-9 csvtable.y*DECODEpxsize(2)*1e-9];
                        poslist = [poslist zeros(size(poslist,1),1)];
                        poslist(poslist(:,1)==0,:)=[];
                    end
                elseif strcmp(fnm(end-2:end),'txt') %RapidSTORM
                    [software_id, ~] = determineLocFile(app,fnm);
                    if software_id ~= 3
                        return
                    end
                    %Read the data
                    RSinfo = readmatrix(fnm);
                    fid = fopen(fnm);
                    FieldNames = fgetl(fid);
                    fclose(fid);
                    %Find the headers
                    k = strfind(FieldNames,'field identifier=');
                    for i = 1:size(k,2)
                        %Get the field names
                        t{i} = FieldNames(k(i)+18:k(i)+29);
                        %Order: frame,x,y
                        if strcmp(t{i},'Position-0-0')
                            columnorder(2) = i;
                        elseif strcmp(t{i},'Position-1-0')
                            columnorder(3) = i;
                        elseif strcmp(t{i},'ImageNumber-')
                            columnorder(1) = i;
                        end
                    end
                    %Get the info in poslist
                    poslist = RSinfo(:,columnorder);
                    %Add 1 to each frame, since RS is 0-starting
                    poslist(:,1)=poslist(:,1)+1;
                    %Set to m rather than nm
                    poslist(:,2:3)=poslist(:,2:3)*1e-9;
                else
                    disp(['Error loading file ' fnm]);
                    disp(['Unsupported file format!']);
                    app.TextArea.Value = [['Error loading file ' fnm]; app.TextArea.Value ];
                    app.TextArea.Value = ['Unsupported file format!'; app.TextArea.Value ];
                    app.TextArea.Value = ['Skipping that file...'; app.TextArea.Value ];
                    drawnow
                    poslist = [];
                end
            catch
                disp(['Error loading file ' fnm]);
                keyboard
                app.TextArea.Value = [['Error loading file ' fnm]; app.TextArea.Value ];
                app.TextArea.Value = ['Skipping that file...'; app.TextArea.Value ];
                drawnow
                poslist = [];
            end
        end
        
        function curFolder = getCurFolderDataLocEditVisField(app)
            %Get folder from AnalysedDataLocationEditField
            try
                %find last value of / or \
                str = app.AnalysedDataLocationEditField.Value;
                lastvalFolder = 0;
                if max(strfind(str,'\')) > lastvalFolder
                    lastvalFolder = max(strfind(str,'\'));
                end
                if max(strfind(str,'/')) > lastvalFolder
                    lastvalFolder = max(strfind(str,'\'));
                end
                curFolder = app.AnalysedDataLocationEditField.Value(1:lastvalFolder);
            catch
                %Get current folder backup in case it's not working
                curFolder = cd;
            end
        end
        
        function setBusyIndicator(app,busyval)
            if busyval
                app.RunTARDISButton.Text = 'Busy...';
                app.RunTARDISButton.Enable = 0;
            else
                app.RunTARDISButton.Text = 'Run TARDIS';
                app.RunTARDISButton.Enable = 1;
            end
        end
        
        function visualiseJD(app)
            %Create visualisation
            Visualisation_JDs(app,'JDhistPanel',app.Data.JDonlydata{1},app);
        end
        
        function [id_software, pxsize_DECODE] = determineLocFile(app, fileLoc)
            %Also initialises loc filters
            % Outputs 1 if it's ThunderSTORM, 2 if it's DECODE, 3 if it's
            % RapidSTORM
            pxsize_DECODE = 0;
            if strcmp(fileLoc(end-2:end),'csv')
                %Test for ThSTORM or DECODE
                fid = fopen(fileLoc);
                %Get the first line
                header = fgetl(fid);
                if strfind(header,"DECODE")
                    id_software = 2;
                    %Get the second line - only version ID, so not used
                    fgetl(fid);
                    %Get px info
                    pxinfoline = fgetl(fid);
                    pxsize_DECODE = eval(pxinfoline(strfind(pxinfoline,"px_size")+10:end-1));
                    %Edit localisation filters if not on 'DECODE' mode
                    if app.RunTARDISButton.Enable
                        if ~strcmp(app.Filter1EditField.Value,'frame_ix')
                            app.Filter1EditField.Value = 'frame_ix';
                            app.Filter1Sign.Value = '>=';
                            app.Filter1ValEditField.Value = 0;
                            app.Filter2EditField.Value = 'x_sig';
                            app.Filter2Sign.Value = '<';
                            app.Filter2ValEditField.Value = pxsize_DECODE(1);
                            app.Filter3EditField.Value = 'y_sig';
                            app.Filter3Sign.Value = '<';
                            app.Filter3ValEditField.Value = pxsize_DECODE(2);
                            app.Filter4EditField.Value = 'prob';
                            app.Filter4Sign.Value = '>=';
                            app.Filter4ValEditField.Value = 0;
                        end
                    end
                elseif contains(header,'"frame"') || contains(header,'frame')
                    id_software = 1;
                    %Edit localisation filters
                    if app.RunTARDISButton.Enable
                        if ~strcmp(app.Filter1EditField.Value,'frame')
                            app.Filter1EditField.Value = 'frame';
                            app.Filter1Sign.Value = '>=';
                            app.Filter1ValEditField.Value = 1;
                            app.Filter2EditField.Value = '';
                            app.Filter2Sign.Value = '<';
                            app.Filter2ValEditField.Value = 0;
                            app.Filter3EditField.Value = '';
                            app.Filter3Sign.Value = '<';
                            app.Filter3ValEditField.Value = 0;
                            app.Filter4EditField.Value = '';
                            app.Filter4Sign.Value = '<';
                            app.Filter4ValEditField.Value = 0;
                        end
                    end
                end
                fclose(fid);
            elseif strcmp(fileLoc(end-2:end),'txt')
                id_software = 3;
            end
            storeUIdata(app);
            %Set tab to loc filter if not 'running'
%             if app.RunTARDISButton.Enable
%                 app.TabGroup.SelectedTab = app.TabGroup.Children(4);
%             end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Mental note that the following 20 lines need to be removed to
            %create executable. some keywords: standalone build building.
            %Add required folders and data to search path
            addpath(genpath(fileparts(mfilename('fullpath'))));

            % Set the CustomPDFDropDown settings
            %Look for all .m files in the parent-folder with some
            %subfolders
            FolderToSearch = [fileparts(mfilename('fullpath')) '\Main\Functions\Core\Fitting functions\CustomPDFs'];
            %FolderToSearch = '.\Main\Functions\Core\Fitting functions\CustomPDFs';

            % Get a list of all files and folders in the specified directory
            dirContents = dir(FolderToSearch);
            % Initialize an empty cell array to store the names of .m files
            mFileNames = {};
            % Loop through the directory contents and filter out .m files
            for i = 1:length(dirContents)
                if ~dirContents(i).isdir && strcmpi(dirContents(i).name(end-1:end), '.m')
                    mFileNames{end+1} = dirContents(i).name;
                end
            end
            %Add it to the dropdown
            app.CustomPDFDropDown.Items = mFileNames;

            %End of line removal for executable creation

            loadUIdata(app);
            
            %Increase size of RightTopPanel larger than can be done via
            %Design
            p =app.RightTopPanel.Position;
            f1 = -40;
            app.RightTopPanel.Position(1) = p(1)+f1;
            app.RightTopPanel.Position(3) = p(3)-2*f1;
            p =app.RightBotPanel.Position;
            app.RightBotPanel.Position(1) = p(1)+f1;
            app.RightBotPanel.Position(3) = p(3)-2*f1;

            
            %% Initiate settingsURDA  
            app.Data.settingsURDA.maxdist = app.MaximumJumpDistanceEditField.Value*1e-6; %in app
            app.Data.settingsURDA.dt_arr = [1:app.MaxtSpinner.Value]; %in app
            app.Data.settingsURDA.frame_time = app.FrametimeEditField.Value*1e-3; %in app
            app.Data.settingsURDA.frame_dist_BG_start = app.LongesttracklengthEditField.Value; % in app
            app.Data.settingsURDA.frame_dist_BG = [app.Data.settingsURDA.frame_dist_BG_start:1:app.Data.settingsURDA.frame_dist_BG_start]; %in app
            app.Data.settingsURDA.performestimationfit = app.PerformEstimationFitCheckBox.Value;
            app.Data.settingsURDA.verbose = app.VerboseCheckBox.Value;
            app.Data.settingsURDA.verboseReal = app.VerboseCheckBox.Value;
            app.Data.settingsURDA.bgbinningnr = app.NrofBGbinsEditField.Value;
            app.Data.settingsURDA.startpointBG = app.StartpointBGratioofmaxJDEditField.Value*app.Data.settingsURDA.maxdist;
            app.Data.settingsURDA.fitWithBleach = app.FitwbleachCheckBox.Value;
            app.Data.settingsURDA.fixRatios2pop = app.pop_fixRatiosCheckBox.Value;
            app.Data.settingsURDA.StoreSWIFTparameters = app.StoreswiftparametersCheckBox.Value;
            app.Data.settingsURDA.StoreMHTparameters = app.StoreMHTparametersCheckBox.Value;
            app.Data.settingsURDA.noiseDensity = app.SwiftParam_noiseDensityEditField.Value;

            app.Data.settingsURDA.lb_1pop = app.lb_1popEditField.Value;
            app.Data.settingsURDA.ub_1pop = app.ub_1popEditField.Value;
            app.Data.settingsURDA.lb_2pop = app.lb_2popEditField.Value;
            app.Data.settingsURDA.ub_2pop = app.ub_2popEditField.Value;
            app.Data.settingsURDA.lb_adda = app.lb_addaEditField.Value;
            app.Data.settingsURDA.ub_adda = app.ub_addaEditField.Value;
            app.Data.settingsURDA.customPDF_start = app.CustomPDF_StartValuesEditField.Value;
            app.Data.settingsURDA.customPDF_upper = app.CustomPDF_UpperboundsEditField.Value;
            app.Data.settingsURDA.customPDF_lower = app.CustomPDF_LowerboundsEditField.Value;

            if strcmp(app.FittingmethodButtonGroup.SelectedObject.Text,'Custom PDF')
                app.Data.settingsURDA.customPDF = app.CustomPDFDropDown.Value(1:end-2);
            else
                app.Data.settingsURDA.customPDF = 0;
            end
            
            switch app.VisualisationscalingButtonGroup.SelectedObject.Text
                case 'Logarithmic'
                    app.Data.settingsURDA.linorlogvis = 'log';
                case 'Linear'
                    app.Data.settingsURDA.linorlogvis = 'lin';
            end
            
            app.Data.settingsURDA.loc_unc = app.LocalizationuncertaintyEditField.Value*1e-9;
            try
                changeBGsubtractnr(app);
            catch ME
                
            end
            switch app.FittingmethodButtonGroup.SelectedObject.Text
                case 'No fitting'
                    app.Data.settingsURDA.AutoChoosePop = 0;
                    app.Data.settingsURDA.populations = 1;
                    app.Data.settingsURDA.createJDsonly = 1;
                    app.CustomPDFsettingsPanel.Visible = 'off';
                case 'Auto-choose pop'
                    app.Data.settingsURDA.AutoChoosePop = 1;
                    app.Data.settingsURDA.populations = 1; %doesn't matter, but requires a value to prevent an warning
                    app.Data.settingsURDA.createJDsonly = 0;
                    app.CustomPDFsettingsPanel.Visible = 'off';
                case '1 population'
                    app.Data.settingsURDA.AutoChoosePop = 0;
                    app.Data.settingsURDA.populations = 1;
                    app.Data.settingsURDA.createJDsonly = 0;
                    app.CustomPDFsettingsPanel.Visible = 'off';
                case '2 populations'
                    app.Data.settingsURDA.AutoChoosePop = 0;
                    app.Data.settingsURDA.populations = 2;
                    app.Data.settingsURDA.createJDsonly = 0;
                    app.CustomPDFsettingsPanel.Visible = 'off';
                case 'aDDA'
                    app.Data.settingsURDA.AutoChoosePop = 0;
                    app.Data.settingsURDA.populations = 0;
                    app.Data.settingsURDA.createJDsonly = 0;
                    app.CustomPDFsettingsPanel.Visible = 'off';
                case 'Custom PDF'
                    app.Data.settingsURDA.AutoChoosePop = 0;
                    app.Data.settingsURDA.populations = 0;
                    app.Data.settingsURDA.createJDsonly = 0;
                    app.CustomPDFsettingsPanel.Visible = 'on';
            end
            
            app.Data.settingsURDA.start_1pop = app.popEditField.Value;
            app.Data.settingsURDA.start_2pop = app.popEditField_2.Value;
            app.Data.settingsURDA.start_aDDA = app.aDDAEditField.Value;
            app.Data.settingsURDA.savemaxdata = app.StorealldataNOTrecommendedverybigfilesizeCheckBox.Value;
            
            if strcmp(app.BGsubtractioninterpolationDropDown.Value,'Linear')
                app.Data.settingsURDA.linorlogBGsubtract = 'lin';
            else
                app.Data.settingsURDA.linorlogBGsubtract = 'log';
            end
            
            app.Data.settingsURDA.inputAnaDDA = Generateinputfile;
            app.Data.settingsURDA.inputAnaDDA.numberofspecies = app.NrofspeciesEditField.Value;
            app.Data.settingsURDA.inputAnaDDA.sigmaerror = app.Data.settingsURDA.loc_unc*1e6; %in um
            app.Data.settingsURDA.inputAnaDDA.confinement = app.CellularconfinementCheckBox.Value; %in app
            app.Data.settingsURDA.inputAnaDDA.radiusofcell = app.DiameterEditField.Value; %in app
            app.Data.settingsURDA.inputAnaDDA.lengthcell = app.LengthEditField.Value; %in app
            app.Data.settingsURDA.inputAnaDDA.trackingwindow = app.Data.settingsURDA.maxdist*1e3; %careful: hard-coded number
            app.Data.settingsURDA.inputAnaDDA.frametimerange = app.Data.settingsURDA.frame_time.*[1:size(app.Data.settingsURDA.dt_arr,2)];
            
            %Strobo duration
            if app.StrobocorrectionButtonGroup.SelectedObject == app.ExcitationpulseButton
                app.Data.settingsURDA.stroboFrameTime = str2double(app.ExcitationpulseDurationField.Value)/1000;
            else
                if app.StrobocorrectionButtonGroup.SelectedObject == app.FullframeexcitationButton
                    app.Data.settingsURDA.stroboFrameTime = -1;
                else
                    app.Data.settingsURDA.stroboFrameTime = 0;
                end
            end

            %Pre-defined variables
            app.Data.settingsURDA.inputAnaDDA.upperDfree = 10; % in um2/s
            app.Data.settingsURDA.inputAnaDDA.compensatetracking = 0;
            app.Data.settingsURDA.inputAnaDDA.fixedparameters(:) = -1;
            app.Data.settingsURDA.inputAnaDDA.framerange = 1; %hardcoded and should always be this way
            app.Data.settingsURDA.inputAnaDDA.precision = 2^16; %Normally 50000
        
            app.Data.settingsURDA.fitvis = 1;
            app.Data.settingsURDA.minlogpoint = 10^-9; %Used to be -9
            app.Data.settingsURDA.norm_bins = 10000;
            %Note: start, lb, ub, all in um2/s
            app.Data.settingsURDA.start_3pop = [0.05 1 29 0.9]; %Startpoint of fitting between 1e-11 and 1e-13
            app.Data.settingsURDA.lb_3pop = [1e-5 1e-5 1e-5 0]; %LB of fit
            app.Data.settingsURDA.ub_3pop = [1e4 1e4 1e4 100]; %UB of fit
            app.Data.settingsURDA.debug = false;
            app.Data.settingsURDA.performsecondfit = 1;
            app.Data.settingsURDA.verboseMLEIntFit = app.Data.settingsURDA.verbose;
            app.Data.settingsURDA.vis = 0;
            app.Data.settingsURDA.fitvisHO = 0;
            app.Data.settingsURDA.visualisationMLEIntFit = 0;
            app.Data.settingsURDA.useAIC = 0;
            app.Data.settingsURDA.freefit_locunc = app.FittinglocalizationuncertaintyCheckBox.Value;
            app.Data.settingsURDA.callfromUI = app;
            
            %Say that it's not busy
            setBusyIndicator(app,0);
            
            %Have some fun with the tooltips
            runButtonTooltipOptions = {'','',''};
            app.RunTARDISButton.Tooltip = runButtonTooltipOptions(randi(size(runButtonTooltipOptions,2)));
        end

        % Button pushed function: RunTARDISButton
        function RunTARDISButtonPushed(app, event)
            setBusyIndicator(app,1);
%           First clear the current results
            clearResultPlots(app);
            %Change open tab to Analysis run info
            app.TabGroup.SelectedTab = app.TabGroup.Children(3);
            drawnow;

            %Ensure createJDsonly is correctly selected
            app.Data.settingsURDA.createJDsonly = 0;
            if strcmp(app.FittingmethodButtonGroup.SelectedObject.Text,'No fitting')
                app.Data.settingsURDA.createJDsonly = 1;
            end
            %% If the no fitting is selected, we only just do JDs
            % Create a list of JDs from this dataset
            if app.Data.settingsURDA.createJDsonly
                %Check if it's a folder or a file
                %If it's a folder
                if isfolder(app.DataLocationEditField.Value)
                    S = dir(fullfile(app.DataLocationEditField.Value,'*.mat'));
                    S = [S; dir(fullfile(app.DataLocationEditField.Value,'*.csv'))];
                    for fl = 1:numel(S)
                        %Display information about file that is now started
                        stringval = ['Starting file ' num2str(fl) ' of ' num2str(numel(S)) ': ' S(fl).name];
                        disp([stringval ' -------------------------------------------'])
                        app.TextArea.Value = [stringval; ' '; app.TextArea.Value];
                        
                        %Load data
                        fnm = fullfile(app.DataLocationEditField.Value,S(fl).name);
                        poslist = loadDataMATCSV(app,fnm);
                        %Run the file
                        if ~isempty(poslist)
                            [app.Data.HOFFfit, app.Data.HOFFfitCI, app.Data.paramEsts, app.Data.HOfitCI, app.Data.tottime, app.Data.time, app.Data.bgarr, app.Data.truthoffsetpartial, app.Data.Visualisation_HO_outputCell,app.Data.Visualisation_FF_outputCell,app.Data.anaDDAvisInfoHO,app.Data.anaDDAvisInfoFF,app.Data.JDonlydata,app.Data.SwiftParams,app.Data.MHTParams] = ...
                                URDA_HO_FF_function(poslist,app.Data.settingsURDA);
                            
                            visualiseJD(app)
                            %Set app.TabGroup2 to JD hist results
                            app.TabGroup2.SelectedTab = app.JumpdistancehistogramsTab;
                            %Create results folder if it doesn't exist yet
                            %Get direct folder of this file
                            ParentFolder = app.DataLocationEditField.Value(1:max(strfind(app.DataLocationEditField.Value,'\'))-1);
                            if ~isfolder([ParentFolder '\TARDIS_Results'])
                                mkdir([ParentFolder '\TARDIS_Results']);
                            end
                
                            %Store data
                            curFileName = S(fl).name;
                            savename = [ParentFolder '\TARDIS_Results\' curFileName(1:end-4) '_TARDISresult.mat'];
                            saveData(app,savename);
                        end
                    end
                else
                     %If it's a single file
                    fnm = app.DataLocationEditField.Value;
                    poslist = loadDataMATCSV(app,fnm);
                    if ~isempty(poslist)
                        [app.Data.HOFFfit, app.Data.HOFFfitCI, app.Data.paramEsts, app.Data.HOfitCI, app.Data.tottime, app.Data.time, app.Data.bgarr, app.Data.truthoffsetpartial, app.Data.Visualisation_HO_outputCell,app.Data.Visualisation_FF_outputCell,app.Data.anaDDAvisInfoHO,app.Data.anaDDAvisInfoFF,app.Data.JDonlydata,app.Data.SwiftParams,app.Data.MHTParams] = ...
                            URDA_HO_FF_function(poslist,app.Data.settingsURDA);
                        
                        visualiseJD(app)
                        %Set app.TabGroup2 to JD hist results
                        app.TabGroup2.SelectedTab = app.JumpdistancehistogramsTab;
                        %Create results folder if it doesn't exist yet
                        %Get direct folder of this file
                        ParentFolder = app.DataLocationEditField.Value(1:max(strfind(app.DataLocationEditField.Value,'\'))-1);
                        if ~isfolder([ParentFolder '\TARDIS_Results'])
                            mkdir([ParentFolder '\TARDIS_Results']);
                        end
            
                        %Store data
                        curFileName = app.DataLocationEditField.Value(max(strfind(app.DataLocationEditField.Value,'\'))+1:end);
                        savename = [ParentFolder '\TARDIS_Results\' curFileName(1:end-4) '_TARDISresult.mat'];
                        saveData(app,savename);
                    end
                end
            %% End JD only
            else
                %% Load the data and run the analysis
                
                %Check if it's a folder or a file
                %If it's a folder
                if isfolder(app.DataLocationEditField.Value)
                    S = dir(fullfile(app.DataLocationEditField.Value,'*.mat'));
                    S = [S; dir(fullfile(app.DataLocationEditField.Value,'*.csv'))];
                    for fl = 1:numel(S)
                    %Display information about file that is now started
                    stringval = ['Starting file ' num2str(fl) ' of ' num2str(numel(S)) ': ' S(fl).name];
                    disp([stringval ' -------------------------------------------'])
                    try
                    app.TextArea.Value = [stringval; ' '; app.TextArea.Value];
    
                    %Load data
                    fnm = fullfile(app.DataLocationEditField.Value,S(fl).name);
                    poslist = loadDataMATCSV(app,fnm);
                    
                    if ~isempty(poslist)
                        %Then show localizations
                        showLocalizations(app,poslist);
                        drawnow
                        %Set app.TabGroup2 to locs
                        app.TabGroup2.SelectedTab = app.LocalizationsTab;
                        drawnow
                        
                        timeEpochStart = int64(milliseconds(datetime('now','Timezone','UTC')-datetime('1970-01-01','Timezone','UTC')));
                        %Run analysis!
                        [app.Data.HOFFfit, app.Data.HOFFfitCI, app.Data.paramEsts, app.Data.HOfitCI, app.Data.tottime, app.Data.time, app.Data.bgarr, app.Data.truthoffsetpartial, app.Data.Visualisation_HO_outputCell,app.Data.Visualisation_FF_outputCell,app.Data.anaDDAvisInfoHO,app.Data.anaDDAvisInfoFF,app.Data.JDonlydata,app.Data.SwiftParams,app.Data.MHTParams] = ...
                        URDA_HO_FF_function(poslist,app.Data.settingsURDA);
                    
                        %Specify nr of out-populations (might change later with
                        %AIC)
                        app.Data.populationsOut = app.Data.settingsURDA.populations;
                        
                        %Show results
                        visualiseResults(app);
                     
                        %Get final time duration
                        timeEpochEnd = int64(milliseconds(datetime('now','Timezone','UTC')-datetime('1970-01-01','Timezone','UTC')));
                        app.Data.timeDuration = timeEpochEnd-timeEpochStart;
                        %Create results folder if it doesn't exist yet
                        if ~isfolder([S(fl).folder '/TARDIS_Results'])
                            mkdir([S(fl).folder '/TARDIS_Results']);
                        end
                        
                        %Store data
                        savename = [S(fl).folder '/TARDIS_Results/' S(fl).name(1:end-4) '_TARDISresult.mat'];
                        saveData(app,savename);
                    end
                    catch
                        disp('This file did not run!')
                    end
                    end
                else
                    %If it's a single file
                    fnm = app.DataLocationEditField.Value;
                    poslist = loadDataMATCSV(app,fnm);
                    if ~isempty(poslist)
                        
                        %Then show localizations
                        showLocalizations(app,poslist);
                        drawnow
                        %Set app.TabGroup2 to locs
                        app.TabGroup2.SelectedTab = app.LocalizationsTab;
                        drawnow
                        
                        timeEpochStart = int64(milliseconds(datetime('now','Timezone','UTC')-datetime('1970-01-01','Timezone','UTC')));
                        
                        %Run analysis!
                        [app.Data.HOFFfit, app.Data.HOFFfitCI, app.Data.paramEsts, app.Data.HOfitCI, app.Data.tottime, app.Data.time, app.Data.bgarr, app.Data.truthoffsetpartial, app.Data.Visualisation_HO_outputCell,app.Data.Visualisation_FF_outputCell,app.Data.anaDDAvisInfoHO,app.Data.anaDDAvisInfoFF,app.Data.JDonlydata,app.Data.SwiftParams,app.Data.MHTParams] = ...
                        URDA_HO_FF_function(poslist,app.Data.settingsURDA);
                        
                        %Specify nr of out-populations (might be changed due to
                        %auto-choosing)
                        app.Data.populationsOut = app.Data.Visualisation_FF_outputCell.populations;%app.Data.settingsURDA.populations;
                        
                        %Show results
                        visualiseResults(app);
                     
                        %Get final time duration
                        timeEpochEnd = int64(milliseconds(datetime('now','Timezone','UTC')-datetime('1970-01-01','Timezone','UTC')));
                        app.Data.timeDuration = timeEpochEnd-timeEpochStart;
                    
                        %Create results folder if it doesn't exist yet
                        %Get direct folder of this file
                        ParentFolder = app.DataLocationEditField.Value(1:max(strfind(app.DataLocationEditField.Value,'\'))-1);
                        if ~isfolder([ParentFolder '\TARDIS_Results'])
                            mkdir([ParentFolder '\TARDIS_Results']);
                        end
                        
                        %Store data
                        curFileName = app.DataLocationEditField.Value(max(strfind(app.DataLocationEditField.Value,'\'))+1:end);
                        savename = [ParentFolder '\TARDIS_Results\' curFileName(1:end-4) '_TARDISresult.mat'];
                        saveData(app,savename);
                    end
                end
            end
            setBusyIndicator(app,0);
        end

        % Value changed function: BGsubtractionaccuracySlider
        function BGsubtractionaccuracySliderValueChanged(app, event)
            changeBGsubtractnr(app);
            %savedata
            storeUIdata(app);
        end

        % Value changed function: DataLocationEditField
        function DataLocationEditFieldValueChanged(app, event)
%             value = app.DataLocationEditField.Value;
            determineLocFile(app, app.DataLocationEditField.Value)
            %savedata
            storeUIdata(app);
        end

        % Value changed function: MaximumJumpDistanceEditField
        function MaximumJumpDistanceEditFieldValueChanged(app, event)
            app.Data.settingsURDA.maxdist = app.MaximumJumpDistanceEditField.Value*1e-6;
            app.Data.settingsURDA.startpointBG = app.StartpointBGratioofmaxJDEditField.Value*app.Data.settingsURDA.maxdist;
            storeUIdata(app);
        end

        % Value changed function: MaxtSpinner
        function MaxtSpinnerValueChanged(app, event)
            app.Data.settingsURDA.dt_arr = [1:app.MaxtSpinner.Value];
            storeUIdata(app);
        end

        % Value changed function: FrametimeEditField
        function FrametimeEditFieldValueChanged(app, event)
            app.Data.settingsURDA.frame_time = app.FrametimeEditField.Value*1e-3;
            storeUIdata(app);
        end

        % Value changed function: LongesttracklengthEditField
        function LongesttracklengthEditFieldValueChanged(app, event)
            app.Data.settingsURDA.frame_dist_BG_start = app.LongesttracklengthEditField.Value;
            changeBGsubtractnr(app);
            storeUIdata(app);
        end

        % Selection changed function: FittingmethodButtonGroup
        function FittingmethodButtonGroupSelectionChanged(app, event)
            switch app.FittingmethodButtonGroup.SelectedObject.Text
                case 'No fitting'
                    app.Data.settingsURDA.AutoChoosePop = 0;
                    app.Data.settingsURDA.populations = 1;
                    app.Data.settingsURDA.createJDsonly = 1;
                    app.Data.settingsURDA.customPDF = 0;
                    app.aDDAsettingsPanel.Visible = 'off';
                    app.CustomPDFsettingsPanel.Visible = 'off';
                case 'Auto-choose pop'
                    app.Data.settingsURDA.AutoChoosePop = 1;
                    app.Data.settingsURDA.populations = 1; %doesn't matter, but requires a value to prevent an warning
                    app.Data.settingsURDA.createJDsonly = 1;
                    app.Data.settingsURDA.customPDF = 0;
                    app.aDDAsettingsPanel.Visible = 'off';
                    app.CustomPDFsettingsPanel.Visible = 'off';
                case '1 population'
                    app.Data.settingsURDA.AutoChoosePop = 0;
                    app.Data.settingsURDA.populations = 1;
                    app.Data.settingsURDA.createJDsonly = 1;
                    app.Data.settingsURDA.customPDF = 0;
                    app.aDDAsettingsPanel.Visible = 'off';
                    app.CustomPDFsettingsPanel.Visible = 'off';
                case '2 populations'
                    app.Data.settingsURDA.AutoChoosePop = 0;
                    app.Data.settingsURDA.populations = 2;
                    app.Data.settingsURDA.createJDsonly = 1;
                    app.Data.settingsURDA.customPDF = 0;
                    app.aDDAsettingsPanel.Visible = 'off';
                    app.CustomPDFsettingsPanel.Visible = 'off';
                case 'aDDA'
                    app.Data.settingsURDA.AutoChoosePop = 0;
                    app.Data.settingsURDA.populations = 0;
                    app.Data.settingsURDA.createJDsonly = 1;
                    app.Data.settingsURDA.customPDF = 0;
                    app.aDDAsettingsPanel.Visible = 'on';
                    app.CustomPDFsettingsPanel.Visible = 'off';
                case 'Custom PDF'
                    app.Data.settingsURDA.AutoChoosePop = 0;
                    app.Data.settingsURDA.populations = 0;
                    app.Data.settingsURDA.createJDsonly = 1;
                    app.Data.settingsURDA.customPDF = 'CustomPDF_Example_1pop';
                    app.aDDAsettingsPanel.Visible = 'off';
                    app.CustomPDFsettingsPanel.Visible = 'on';
            end
            storeUIdata(app);
        end

        % Value changed function: LocalizationuncertaintyEditField
        function LocalizationuncertaintyEditFieldValueChanged(app, event)
            app.Data.settingsURDA.loc_unc = app.LocalizationuncertaintyEditField.Value*1e-9;
            app.Data.settingsURDA.inputAnaDDA.sigmaerror = app.Data.settingsURDA.loc_unc*1e6;
            storeUIdata(app);
        end

        % Value changed function: NrofspeciesEditField
        function NrofspeciesEditFieldValueChanged(app, event)
            app.Data.settingsURDA.inputAnaDDA.numberofspecies = app.NrofspeciesEditField.Value;
            storeUIdata(app);
        end

        % Value changed function: DiameterEditField
        function DiameterEditFieldValueChanged(app, event)
            app.Data.settingsURDA.inputAnaDDA.radiusofcell = app.DiameterEditField.Value;
            storeUIdata(app);
        end

        % Value changed function: LengthEditField
        function LengthEditFieldValueChanged(app, event)
            app.Data.settingsURDA.inputAnaDDA.lengthofcell = app.LengthEditField.Value;
            storeUIdata(app);
        end

        % Value changed function: CellularconfinementCheckBox
        function CellularconfinementCheckBoxValueChanged(app, event)
            app.Data.settingsURDA.inputAnaDDA.confinement = app.CellularconfinementCheckBox.Value;
            if app.CellularconfinementCheckBox.Value
                app.LengthEditField.Visible = 1;
                app.LengthEditFieldLabel.Visible = 1;
                app.DiameterEditField.Visible = 1;
                app.DiameterEditFieldLabel.Visible = 1;
                app.UnitLabel1_anaddarad.Visible = 1;
                app.UnitLabel1_anaddalen.Visible = 1;
            else
                app.LengthEditField.Visible = 0;
                app.LengthEditFieldLabel.Visible = 0;
                app.DiameterEditField.Visible = 0;
                app.DiameterEditFieldLabel.Visible = 0;
                app.UnitLabel1_anaddarad.Visible = 0;
                app.UnitLabel1_anaddalen.Visible = 0;
            end
        end

        % Button pushed function: FileLocationFinder
        function FileLocationFinderButtonPushed(app, event)
            %Get current folder structure in DataLocationEditField and
            %start there
            curFolder = getCurFolderDataLocEditField(app);
            %Go to UI finder and select a file
            [file,path] = uigetfile({[curFolder '*.mat;*.csv;*.txt'];},'Select a data file');
            if file ~= 0 %if something is selected
                app.DataLocationEditField.Value = [path file];
                storeUIdata(app);
            end
            %Figure out if new file location is ThunderSTORM, SMAP, or
            %DECODE
            determineLocFile(app, app.DataLocationEditField.Value);
            %Make UI front again
            figure(app.TARDISTemporalAnalysisofRelativeDistancesUIFigure)
            drawnow;
            
        end

        % Button pushed function: FolderLocationFinder
        function FolderLocationFinderButtonPushed(app, event)
            %Go to UI finder and select a folder
            %Get current folder structure in DataLocationEditField and
            %start there
            curFolder = getCurFolderDataLocEditField(app);
            %Go to UI finder and select a file
            selpath = uigetdir(curFolder,'Select a folder');
            if selpath ~= 0 %if something is selected
                app.DataLocationEditField.Value = [selpath];
                storeUIdata(app);
            end
            %Make UI front again
            figure(app.TARDISTemporalAnalysisofRelativeDistancesUIFigure)
            drawnow;
        end

        % Value changed function: PerformEstimationFitCheckBox
        function PerformEstimationFitCheckBoxValueChanged(app, event)
            app.Data.settingsURDA.performestimationfit = app.PerformEstimationFitCheckBox.Value;
            storeUIdata(app);
        end

        % Value changed function: VerboseCheckBox
        function VerboseCheckBoxValueChanged(app, event)
            app.Data.settingsURDA.verboseReal = app.VerboseCheckBox.Value;
            storeUIdata(app);
        end

        % Value changed function: NrofBGbinsEditField
        function NrofBGbinsEditFieldValueChanged(app, event)
            app.Data.settingsURDA.bgbinningnr = app.NrofBGbinsEditField.Value;
            storeUIdata(app);
        end

        % Selection changed function: VisualisationscalingButtonGroup
        function VisualisationscalingButtonGroupSelectionChanged(app, event)
            switch app.VisualisationscalingButtonGroup.SelectedObject.Text
                case 'Logarithmic'
                    app.Data.settingsURDA.linorlogvis = 'log';
                case 'Linear'
                    app.Data.settingsURDA.linorlogvis = 'lin';
            end
            
            storeUIdata(app);
        end

        % Value changed function: StartpointBGratioofmaxJDEditField
        function StartpointBGratioofmaxJDEditFieldValueChanged(app, event)
            app.Data.settingsURDA.startpointBG = app.StartpointBGratioofmaxJDEditField.Value*app.Data.settingsURDA.maxdist;
            
            storeUIdata(app);
        end

        % Button pushed function: VisualiseoldresultsButton
        function VisualiseoldresultsButtonPushed(app, event)
            %Load data
            %Current expected folder according to DataLocationEditField:
            slashid = max(strfind(app.DataLocationEditField.Value,'\'));
            TARDISfolder = [app.DataLocationEditField.Value(1:slashid) '\TARDIS_Results'];
            MotherFolder = [app.DataLocationEditField.Value(1:slashid) '\TARDIS_Results'];
            if exist(TARDISfolder)
                currFolder = TARDISfolder;
            elseif exist(MotherFolder)
                currFolder = MotherFolder;
            else
                currFolder = '';
            end
            [file, path] = uigetfile({'*_TARDISresult.mat','*.mat'},'Select TARDIS result',currFolder);
            load([path file]);
                
            %First clear previous result plots
            clearResultPlots(app);
            
            try
            %Show FF (fullfit results)
%                 Visualisation_FF(app,'RightBotPanel',appinfo.Visualisation_FF_outputCell);
                Visualisation_FF(app,'RightBotPanel',appinfo);
            catch
                disp('Error in visualisation of data')
            end
            
            if app.VisualisealsoinseparatewindowCheckBox.Value
                try
%                     Visualisation_FF([],[],appinfo.Visualisation_FF_outputCell);
                    Visualisation_FF([],[],appinfo);
                catch
                    disp('Error in visualisation of data')
                end
            end

            try
            %Show HO (first fit results)
            Visualisation_HO_BGsubtract(app,'RightTopPanel',appinfo.Visualisation_HO_outputCell.bgbinningnr,appinfo.Visualisation_HO_outputCell.dt_arr,appinfo.Visualisation_HO_outputCell.JDarrBG,appinfo.Visualisation_HO_outputCell.maxdist,appinfo.Visualisation_HO_outputCell.minlogpoint,appinfo.Visualisation_HO_outputCell.linorlogBGsubtract,appinfo.Visualisation_HO_outputCell.bgarr,appinfo.Visualisation_HO_outputCell.JDarrSignalCell,...
            appinfo.Visualisation_HO_outputCell.signalCurve_interp,appinfo.Visualisation_HO_outputCell.stepsizearraytrue,appinfo.Visualisation_HO_outputCell.bgratios,appinfo.Visualisation_HO_outputCell.startpointBG,app);
            catch
            end

            %Show written results
            ShowResults(app,appinfo);
            
        end

        % Button pushed function: ShowlocalizationsButton
        function ShowlocalizationsButtonPushed(app, event)
            
            %If it's a folder
            if isfolder(app.DataLocationEditField.Value)
                S = dir(fullfile(app.DataLocationEditField.Value,'*.mat'));
                for fl = 1:1
                    %Load data
                    fnm = fullfile(app.DataLocationEditField.Value,S(fl).name);
                    poslist = loadDataMATCSV(app,fnm);
                end
            else
                %If it's a file
                fnm = app.DataLocationEditField.Value;
                poslist = loadDataMATCSV(app,fnm);
            end
            
            %Then show localizations
            showLocalizations(app,poslist);
            
            %Set app.TabGroup2 to locs
            app.TabGroup2.SelectedTab = app.LocalizationsTab;
        end

        % Value changed function: popEditField
        function popEditFieldValueChanged(app, event)
            app.Data.settingsURDA.start_1pop = app.popEditField.Value;
            storeUIdata(app);
        end

        % Value changed function: popEditField_2
        function popEditField_2ValueChanged(app, event)
            app.Data.settingsURDA.start_2pop = app.popEditField_2.Value;
            storeUIdata(app);
        end

        % Value changed function: aDDAEditField
        function aDDAEditFieldValueChanged(app, event)
            app.Data.settingsURDA.start_aDDA = app.aDDAEditField.Value;
            storeUIdata(app);
        end

        % Button pushed function: FileLocationFinder_vis
        function FileLocationFinder_visButtonPushed(app, event)
            %Get current folder structure in DataLocationEditField and
            %start there
            curFolder = getCurFolderDataLocEditVisField(app);
            %Go to UI finder and select a file
            [file,path] = uigetfile({[curFolder '*_TARDISresult.mat'];},'Select a data file');
            if file ~= 0 %if something is selected
                app.AnalysedDataLocationEditField.Value = [path file];
                storeUIdata(app);
            end
            %make UI front again
            figure(app.TARDISTemporalAnalysisofRelativeDistancesUIFigure);
            drawnow;
        end

        % Button pushed function: FolderLocationFinder_vis
        function FolderLocationFinder_visButtonPushed(app, event)
            %Go to UI finder and select a folder
            %Get current folder structure in DataLocationEditField and
            %start there
            curFolder = getCurFolderDataLocEditVisField(app);
            %Go to UI finder and select a file
            selpath = uigetdir(curFolder,'Select a folder');
            if selpath ~= 0 %if something is selected
                app.AnalysedDataLocationEditField.Value = [selpath];
                storeUIdata(app);
            end
            %make UI front again
            figure(app.TARDISTemporalAnalysisofRelativeDistancesUIFigure);
            drawnow;
        end

        % Value changed function: BGsubtractioninterpolationDropDown
        function BGsubtractioninterpolationDropDownValueChanged(app, event)
            if strcmp(app.BGsubtractioninterpolationDropDown.Value,'Linear')
                app.Data.settingsURDA.linorlogBGsubtract = 'lin';
            else
                app.Data.settingsURDA.linorlogBGsubtract = 'log';
            end
            storeUIdata(app);
        end

        % Button pushed function: ResetRunButton
        function ResetRunButtonPushed(app, event)
            % Check if we press the button while operation is in process
%             if ~app.RunTARDISButton.Enable
%                 useCtrlC = 1;
%             else
%                 useCtrlC = 0;
%             end
            setBusyIndicator(app,0);
%             if useCtrlC
%                 %KILL IT!
%                 %Basically the Ctrl-C command to abruptly end TARDIS
%                 com.mathworks.mde.cmdwin.CmdWinMLIF.processKeyFromC(2,67,'C');
%             end

        end

        % Value changed function: 
        % StorealldataNOTrecommendedverybigfilesizeCheckBox
        function StorealldataNOTrecommendedverybigfilesizeCheckBoxValueChanged(app, event)
            %If this value is turned on, also change the store mat info
            if app.StorealldataNOTrecommendedverybigfilesizeCheckBox.Value
                app.StoreMATfitdatarecommendedCheckBox.Value = 1;
            end
            storeUIdata(app);
        end

        % Button pushed function: makeJDs
        function makeJDsButtonPushed(app, event)
           
        end

        % Button pushed function: GuessfromdataButton
        function GuessfromdataButtonPushed(app, event)
            %% Perform the Wilcoxon statistical test to determine max track length of data
            %Check if it's a folder or a file
            %Set to busy
            setBusyIndicator(app,1);
            app.GuessfromdataButton.Enable = 0;
            drawnow
            %If it's a folder it cannot work
            if isfolder(app.DataLocationEditField.Value)
                disp('Determining longest track length only works on files!')
                app.TextArea.Value = ['Determining longest track length only works on files!'; app.TextArea.Value ];
            else %If it's a file
                %Load file content
                fnm = app.DataLocationEditField.Value;
                poslist = loadDataMATCSV(app,fnm);
                %Perform Wilcoxon test with normal values and output in UI
                app.LongesttracklengthEditField.Value = BGLengthDetermination_Wilcoxon(poslist,50,10,app.MaximumJumpDistanceEditField.Value*1e-6,0);
                storeUIdata(app);
            end
            setBusyIndicator(app,0);
            app.GuessfromdataButton.Enable = 1;
            drawnow
        end

        % Value changed function: FitwbleachCheckBox
        function FitwbleachCheckBoxValueChanged(app, event)
            app.Data.settingsURDA.fitWithBleach = app.FitwbleachCheckBox.Value;
            storeUIdata(app);
        end

        % Value changed function: StoreJSONfitdatarecommendedCheckBox
        function StoreJSONfitdatarecommendedCheckBoxValueChanged(app, event)
           storeUIdata(app);
        end

        % Value changed function: StoreMATfitdatarecommendedCheckBox
        function StoreMATfitdatarecommendedCheckBoxValueChanged(app, event)
            %Turn big storage off when turned off
            if app.StoreMATfitdatarecommendedCheckBox.Value == 0
                app.StorealldataNOTrecommendedverybigfilesizeCheckBox.Value = 0;
            end
            storeUIdata(app);
        end

        % Value changed function: StoreJDhistogramimageCheckBox
        function StoreJDhistogramimageCheckBoxValueChanged(app, event)
            storeUIdata(app);
        end

        % Value changed function: StoreintermediatefitimageCheckBox
        function StoreintermediatefitimageCheckBoxValueChanged(app, event)
            storeUIdata(app);
        end

        % Value changed function: StorefinalfitimageCheckBox
        function StorefinalfitimageCheckBoxValueChanged(app, event)
            storeUIdata(app);
        end

        % Button pushed function: OpenstoragefolderButton
        function OpenstoragefolderButtonPushed(app, event)
            %Open the storage folder
            %Check if it exists, then open it. Otherwise, open the parent
            %folder where the files to be analysed are
            %If a file is selected
            if ~isfolder(app.DataLocationEditField.Value)
                %Find the last \
                storageFolder = [app.DataLocationEditField.Value(1:max(strfind(app.DataLocationEditField.Value,'\'))) 'TARDIS_Results'];
            %Else, if a folder is selected
            else
                %Remove
                storageFolder = [app.DataLocationEditField.Value '\TARDIS_Results'];
            end
            %Open the folder
            try
                %Check if the folder exists
                if exist(storageFolder, 'dir')
                    winopen(storageFolder);
                else
                    winopen(storageFolder(1:end-15))
                end
            catch
                disp('Folder cannot be opened')
                app.TextArea.Value = ['Folder cannot be opened'; app.TextArea.Value];
            end
        end

        % Value changed function: pop_fixRatiosCheckBox
        function pop_fixRatiosCheckBoxValueChanged(app, event)
            app.Data.settingsURDA.fixRatios2pop = app.pop_fixRatiosCheckBox.Value;
            storeUIdata(app);
        end

        % Value changed function: lb_1popEditField
        function lb_1popEditFieldValueChanged(app, event)
            app.Data.settingsURDA.lb_1pop = app.lb_1popEditField.Value;
            storeUIdata(app);
        end

        % Value changed function: ub_1popEditField
        function ub_1popEditFieldValueChanged(app, event)
            app.Data.settingsURDA.ub_1pop = app.ub_1popEditField.Value;
            storeUIdata(app);
        end

        % Value changed function: lb_2popEditField
        function lb_2popEditFieldValueChanged(app, event)
            app.Data.settingsURDA.lb_2pop = app.lb_2popEditField.Value;
            storeUIdata(app);
        end

        % Value changed function: ub_2popEditField
        function ub_2popEditFieldValueChanged(app, event)
            app.Data.settingsURDA.ub_2pop = app.ub_2popEditField.Value;
            storeUIdata(app);
        end

        % Value changed function: lb_addaEditField
        function lb_addaEditFieldValueChanged(app, event)
            app.Data.settingsURDA.lb_adda = app.lb_addaEditField.Value;
            storeUIdata(app);
        end

        % Value changed function: ub_addaEditField
        function ub_addaEditFieldValueChanged(app, event)
            app.Data.settingsURDA.ub_adda = app.ub_addaEditField.Value;
            storeUIdata(app);
        end

        % Value changed function: Filter1EditField, Filter1Sign, 
        % Filter1ValEditField, Filter2EditField, Filter2Sign, 
        % Filter2ValEditField, Filter3EditField, Filter3Sign, 
        % Filter3ValEditField, Filter4EditField, Filter4Sign, 
        % Filter4ValEditField, Filter5EditField, Filter5Sign, 
        % Filter5ValEditField
        function Filter1EditFieldValueChanged(app, event)
            %Editing of any of the loc filter fields
            app.Data.settingsURDA.loc_filter = {...
                [app.Filter1EditField.Value,app.Filter1Sign.Value,num2str(app.Filter1ValEditField.Value)],...
                [app.Filter2EditField.Value,app.Filter2Sign.Value,num2str(app.Filter2ValEditField.Value)],...
                [app.Filter3EditField.Value,app.Filter3Sign.Value,num2str(app.Filter3ValEditField.Value)],...
                [app.Filter4EditField.Value,app.Filter4Sign.Value,num2str(app.Filter4ValEditField.Value)],...
                [app.Filter5EditField.Value,app.Filter5Sign.Value,num2str(app.Filter5ValEditField.Value)],...
                };
            storeUIdata(app);
        end

        % Callback function
        function Filter1SignValueChanged(app, event)
           
            %savedata
            storeUIdata(app);
        end

        % Callback function
        function Filter1ValEditFieldValueChanged(app, event)

            %savedata
            storeUIdata(app);
        end

        % Selection changed function: StrobocorrectionButtonGroup
        function StrobocorrectionButtonGroupSelectionChanged(app, event)
            selectedButton = app.StrobocorrectionButtonGroup.SelectedObject;

            %Enable/disable excitation pulse button and label
            if selectedButton == app.ExcitationpulseButton
                app.msLabel.Enable = 1;
                app.Data.settingsURDA.stroboFrameTime = str2double(app.ExcitationpulseDurationField.Value)/1000;
                app.ExcitationpulseDurationField.Enable = 1;
            else
                if selectedButton == app.FullframeexcitationButton
                    app.Data.settingsURDA.stroboFrameTime = -1;
                else
                    app.Data.settingsURDA.stroboFrameTime = 0;
                end
                app.msLabel.Enable = 0;
                app.ExcitationpulseDurationField.Enable = 0;
            end

            storeUIdata(app);
        end

        % Value changed function: ExcitationpulseDurationField
        function ExcitationpulseDurationFieldValueChanged(app, event)
            app.Data.settingsURDA.stroboFrameTime = str2double(app.ExcitationpulseDurationField.Value)/1000;
            storeUIdata(app); 
        end

        % Value changed function: FittinglocalizationuncertaintyCheckBox
        function FittinglocalizationuncertaintyCheckBoxValueChanged(app, event)
            app.Data.settingsURDA.freefit_locunc = app.FittinglocalizationuncertaintyCheckBox.Value;
            storeUIdata(app); 
        end

        % Value changed function: StoreswiftparametersCheckBox
        function StoreswiftparametersCheckBoxValueChanged(app, event)
            app.Data.settingsURDA.StoreSWIFTparameters = app.StoreswiftparametersCheckBox.Value;
            storeUIdata(app); 
        end

        % Value changed function: VisualisealsoinseparatewindowCheckBox
        function VisualisealsoinseparatewindowCheckBoxValueChanged(app, event)
            storeUIdata(app); 
        end

        % Value changed function: SwiftParam_noiseDensityEditField
        function SwiftParam_noiseDensityEditFieldValueChanged(app, event)
            app.Data.settingsURDA.noiseDensity = app.SwiftParam_noiseDensityEditField.Value;
            storeUIdata(app);
        end

        % Value changed function: StoreMHTparametersCheckBox
        function StoreMHTparametersCheckBoxValueChanged(app, event)
            app.Data.settingsURDA.StoreMHTparameters = app.StoreMHTparametersCheckBox.Value;
            storeUIdata(app); 
        end

        % Value changed function: CustomPDFDropDown
        function CustomPDFDropDownValueChanged(app, event)
            %Set the customPDF to the name of the .m file.
            app.Data.settingsURDA.customPDF = app.CustomPDFDropDown.Value(1:end-2);
            app.Data.settingsURDA.customPDF_start = app.CustomPDF_StartValuesEditField.Value;
            app.Data.settingsURDA.customPDF_upper = app.CustomPDF_UpperboundsEditField.Value;
            app.Data.settingsURDA.customPDF_lower = app.CustomPDF_LowerboundsEditField.Value;
            %savedata
            storeUIdata(app);
        end

        % Value changed function: CustomPDF_StartValuesEditField
        function CustomPDF_StartValuesEditFieldValueChanged(app, event)
            %Set the customPDF to the name of the .m file.
            app.Data.settingsURDA.customPDF = app.CustomPDFDropDown.Value(1:end-2);
            app.Data.settingsURDA.customPDF_start = app.CustomPDF_StartValuesEditField.Value;
            app.Data.settingsURDA.customPDF_upper = app.CustomPDF_UpperboundsEditField.Value;
            app.Data.settingsURDA.customPDF_lower = app.CustomPDF_LowerboundsEditField.Value;
            %savedata
            storeUIdata(app);
        end

        % Value changed function: CustomPDF_LowerboundsEditField
        function CustomPDF_LowerboundsEditFieldValueChanged(app, event)
            %Set the customPDF to the name of the .m file.
            app.Data.settingsURDA.customPDF = app.CustomPDFDropDown.Value(1:end-2);
            app.Data.settingsURDA.customPDF_start = app.CustomPDF_StartValuesEditField.Value;
            app.Data.settingsURDA.customPDF_upper = app.CustomPDF_UpperboundsEditField.Value;
            app.Data.settingsURDA.customPDF_lower = app.CustomPDF_LowerboundsEditField.Value;
            %savedata
            storeUIdata(app);
        end

        % Value changed function: CustomPDF_UpperboundsEditField
        function CustomPDF_UpperboundsEditFieldValueChanged(app, event)
            %Set the customPDF to the name of the .m file.
            app.Data.settingsURDA.customPDF = app.CustomPDFDropDown.Value(1:end-2);
            app.Data.settingsURDA.customPDF_start = app.CustomPDF_StartValuesEditField.Value;
            app.Data.settingsURDA.customPDF_upper = app.CustomPDF_UpperboundsEditField.Value;
            app.Data.settingsURDA.customPDF_lower = app.CustomPDF_LowerboundsEditField.Value;
            %savedata
            storeUIdata(app);
        end

        % Close request function: 
        % TARDISTemporalAnalysisofRelativeDistancesUIFigure
        function TARDISTemporalAnalysisofRelativeDistancesUIFigureCloseRequest(app, event)
            storeUIdata(app)
            delete(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.TARDISTemporalAnalysisofRelativeDistancesUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {590, 590};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {434, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TARDISTemporalAnalysisofRelativeDistancesUIFigure = uifigure('Visible', 'off');
            app.TARDISTemporalAnalysisofRelativeDistancesUIFigure.Name = 'TARDIS - Temporal Analysis of Relative Distances';
            app.TARDISTemporalAnalysisofRelativeDistancesUIFigure.Icon = 'icon_whiteBG2.png';
            app.TARDISTemporalAnalysisofRelativeDistancesUIFigure.CloseRequestFcn = createCallbackFcn(app, @TARDISTemporalAnalysisofRelativeDistancesUIFigureCloseRequest, true);
            app.TARDISTemporalAnalysisofRelativeDistancesUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.TARDISTemporalAnalysisofRelativeDistancesUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SettingsPanel = uipanel(app.LeftPanel);
            app.SettingsPanel.Title = 'Settings';
            app.TabGroup = uitabgroup(app.SettingsPanel);
            app.BasicTab = uitab(app.TabGroup);
            app.BasicTab.Title = 'Basic';
            app.BGsubtractionaccuracySliderLabel = uilabel(app.BasicTab);
            app.BGsubtractionaccuracySliderLabel.Text = 'BG subtraction accuracy';
            app.BGsubtractionaccuracySlider = uislider(app.BasicTab);
            app.BGsubtractionaccuracySlider.MajorTicks = [0 100];
            app.BGsubtractionaccuracySlider.MajorTickLabels = {'Fast & inaccurate', 'Slow & exact'};
            app.BGsubtractionaccuracySlider.ValueChangedFcn = createCallbackFcn(app, @BGsubtractionaccuracySliderValueChanged, true);
            app.BGsubtractionaccuracySlider.MinorTicks = [25 50 75];
            app.BGsubtractionaccuracySlider.Value = 50;
            app.MaximumJumpDistanceEditField_2Label = uilabel(app.BasicTab);
            app.MaximumJumpDistanceEditField_2Label.Text = 'Maximum Jump Distance';
            app.MaximumJumpDistanceEditField = uieditfield(app.BasicTab, 'numeric');
            app.MaximumJumpDistanceEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumJumpDistanceEditFieldValueChanged, true);
            app.MaximumJumpDistanceEditField.Tooltip = {'The maximum jump distance that TARDIS will analyse.'};
            app.MaximumJumpDistanceEditField.Value = 3;
            app.UnitLabel1 = uilabel(app.BasicTab);
            app.UnitLabel1.Text = 'μm';
            app.MaxtSpinnerLabel = uilabel(app.BasicTab);
            app.MaxtSpinnerLabel.Text = 'Max Δt';
            app.MaxtSpinner = uispinner(app.BasicTab);
            app.MaxtSpinner.Limits = [1 12];
            app.MaxtSpinner.ValueChangedFcn = createCallbackFcn(app, @MaxtSpinnerValueChanged, true);
            app.MaxtSpinner.Tooltip = {'Up to which delta-t (in frames) TARDIS will perform the analysis. Normally, a value of 2-4 is recommended, but depends on signal-to-noise ratio and average track length.'};
            app.MaxtSpinner.Value = 3;
            app.FrametimeEditFieldLabel = uilabel(app.BasicTab);
            app.FrametimeEditFieldLabel.Text = 'Frame time';
            app.FrametimeEditField = uieditfield(app.BasicTab, 'numeric');
            app.FrametimeEditField.Limits = [0 Inf];
            app.FrametimeEditField.ValueChangedFcn = createCallbackFcn(app, @FrametimeEditFieldValueChanged, true);
            app.FrametimeEditField.Tooltip = {'Frame time of the experiment in ms'};
            app.FrametimeEditField.Value = 10;
            app.UnitLabel2 = uilabel(app.BasicTab);
            app.UnitLabel2.Text = 'ms';
            app.LongesttracklengthEditFieldLabel = uilabel(app.BasicTab);
            app.LongesttracklengthEditFieldLabel.Text = 'Longest track length';
            app.LongesttracklengthEditField = uieditfield(app.BasicTab, 'numeric');
            app.LongesttracklengthEditField.Limits = [10 Inf];
            app.LongesttracklengthEditField.ValueChangedFcn = createCallbackFcn(app, @LongesttracklengthEditFieldValueChanged, true);
            app.LongesttracklengthEditField.Tooltip = {'The (expected) length of the longest track in frames. It''s better to over-estimate this value than to under-estimate the value. Over-estimation has no real downside except for fewer datapoints to determine background from.'};
            app.LongesttracklengthEditField.Value = 50;
            app.UnitLabel2_2 = uilabel(app.BasicTab);
            app.UnitLabel2_2.Text = 'frames';
            app.FittingmethodButtonGroup = uibuttongroup(app.BasicTab);
            app.FittingmethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FittingmethodButtonGroupSelectionChanged, true);
            app.FittingmethodButtonGroup.Tooltip = {'What fitting method should be used.'; '''1 population'' tries to fit a single species.'; '''2 populations'' tries to fit two populations in any ratio.'; '''anaDDA'' will fit an inter-changing species from bound to freely-diffusing with certain on- and off-rates.'};
            app.FittingmethodButtonGroup.Title = 'Fitting method';
            app.OnePopButton = uiradiobutton(app.FittingmethodButtonGroup);
            app.OnePopButton.Text = '1 population';
            app.OnePopButton.Value = true;
            app.TwoPopButton = uiradiobutton(app.FittingmethodButtonGroup);
            app.TwoPopButton.Text = '2 populations';
            app.aDDAButton = uiradiobutton(app.FittingmethodButtonGroup);
            app.aDDAButton.Text = 'aDDA';
            app.pop_fixRatiosCheckBox = uicheckbox(app.FittingmethodButtonGroup);
            app.pop_fixRatiosCheckBox.ValueChangedFcn = createCallbackFcn(app, @pop_fixRatiosCheckBoxValueChanged, true);
            app.pop_fixRatiosCheckBox.Tooltip = {'When true, it is assumed that the ratio of populations stays constant throughout DTs. If not selected, the ratio is freely chosen at all DTs. Fitting with bleaching curves overrides this setting.'};
            app.pop_fixRatiosCheckBox.Text = '2pop_fixRatios';
            app.FitwbleachCheckBox = uicheckbox(app.FittingmethodButtonGroup);
            app.FitwbleachCheckBox.ValueChangedFcn = createCallbackFcn(app, @FitwbleachCheckBoxValueChanged, true);
            app.FitwbleachCheckBox.Tooltip = {'When checked, the decrease in signal over dt-bins is fitted to a bleaching curve, rather than a free-fit.'};
            app.FitwbleachCheckBox.Text = 'Fit w/ bleach';
            app.FitwbleachCheckBox.Value = true;
            app.AutoChoosePop = uiradiobutton(app.FittingmethodButtonGroup);
            app.AutoChoosePop.Text = 'Auto-choose pop';
            app.NoFitting = uiradiobutton(app.FittingmethodButtonGroup);
            app.NoFitting.Text = 'No fitting';
            app.customPDFButton = uiradiobutton(app.FittingmethodButtonGroup);
            app.customPDFButton.Text = 'Custom PDF';
            app.aDDAsettingsPanel = uipanel(app.BasicTab);
            app.aDDAsettingsPanel.Title = 'aDDAsettings';
            app.aDDAsettingsPanel.Visible = 'off';
            app.NrofspeciesEditFieldLabel = uilabel(app.aDDAsettingsPanel);
            app.NrofspeciesEditFieldLabel.Visible = 'off';
            app.NrofspeciesEditFieldLabel.Text = 'Nr of species';
            app.NrofspeciesEditField = uieditfield(app.aDDAsettingsPanel, 'numeric');
            app.NrofspeciesEditField.Limits = [1 3];
            app.NrofspeciesEditField.ValueChangedFcn = createCallbackFcn(app, @NrofspeciesEditFieldValueChanged, true);
            app.NrofspeciesEditField.Visible = 'off';
            app.NrofspeciesEditField.Tooltip = {'How many species (i.e. interchanging proteins) should be fit.'};
            app.NrofspeciesEditField.Value = 1;
            app.CellularconfinementCheckBox = uicheckbox(app.aDDAsettingsPanel);
            app.CellularconfinementCheckBox.ValueChangedFcn = createCallbackFcn(app, @CellularconfinementCheckBoxValueChanged, true);
            app.CellularconfinementCheckBox.Tooltip = {'Opting for cellular confinement calculations within anaDDA.'};
            app.CellularconfinementCheckBox.Text = 'Cellular confinement';
            app.CellularconfinementCheckBox.Value = true;
            app.DiameterEditFieldLabel = uilabel(app.aDDAsettingsPanel);
            app.DiameterEditFieldLabel.Text = 'Diameter';
            app.DiameterEditField = uieditfield(app.aDDAsettingsPanel, 'numeric');
            app.DiameterEditField.Limits = [0 Inf];
            app.DiameterEditField.ValueChangedFcn = createCallbackFcn(app, @DiameterEditFieldValueChanged, true);
            app.DiameterEditField.Value = 1;
            app.LengthEditFieldLabel = uilabel(app.aDDAsettingsPanel);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.aDDAsettingsPanel, 'numeric');
            app.LengthEditField.Limits = [0 Inf];
            app.LengthEditField.ValueChangedFcn = createCallbackFcn(app, @LengthEditFieldValueChanged, true);
            app.LengthEditField.Value = 2.5;
            app.UnitLabel1_anaddalen = uilabel(app.aDDAsettingsPanel);
            app.UnitLabel1_anaddalen.Text = 'μm';
            app.UnitLabel1_anaddarad = uilabel(app.aDDAsettingsPanel);
            app.UnitLabel1_anaddarad.Text = 'μm';
            app.UnitLabel2_3 = uilabel(app.BasicTab);
            app.UnitLabel2_3.Text = 'nm';
            app.LocalizationuncertaintyEditFieldLabel = uilabel(app.BasicTab);
            app.LocalizationuncertaintyEditFieldLabel.Text = 'Localization uncertainty';
            app.LocalizationuncertaintyEditField = uieditfield(app.BasicTab, 'numeric');
            app.LocalizationuncertaintyEditField.Limits = [0 Inf];
            app.LocalizationuncertaintyEditField.ValueChangedFcn = createCallbackFcn(app, @LocalizationuncertaintyEditFieldValueChanged, true);
            app.LocalizationuncertaintyEditField.Tooltip = {'(Expected) localization uncertainty of the experiment in nm. Will be used throughout the analysis.'};
            app.LocalizationuncertaintyEditField.Value = 30;
            app.GuessfromdataButton = uibutton(app.BasicTab, 'push');
            app.GuessfromdataButton.ButtonPushedFcn = createCallbackFcn(app, @GuessfromdataButtonPushed, true);
            app.GuessfromdataButton.Text = 'Guess from data';
            app.CustomPDFsettingsPanel = uipanel(app.BasicTab);
            app.CustomPDFsettingsPanel.Tooltip = {'It will only find files that are stored in TARDIS-public\Main\Functions\Core\Fitting functions\CustomPDFs'};
            app.CustomPDFsettingsPanel.Title = 'Custom PDF settings';
            app.CustomPDFsettingsPanel.Visible = 'off';
            app.PDFLabel = uilabel(app.CustomPDFsettingsPanel);
            app.PDFLabel.Text = 'PDF';
            app.CustomPDFDropDown = uidropdown(app.CustomPDFsettingsPanel);
            app.CustomPDFDropDown.Items = {'Custom PDF not working,', 'Use MATLAB installed TARDIS,', 'for custom PDF'};
            app.CustomPDFDropDown.ValueChangedFcn = createCallbackFcn(app, @CustomPDFDropDownValueChanged, true);
            app.CustomPDFDropDown.Value = 'Custom PDF not working,';
            app.StartValuesEditFieldLabel = uilabel(app.CustomPDFsettingsPanel);
            app.StartValuesEditFieldLabel.Text = 'Start Values';
            app.CustomPDF_StartValuesEditField = uieditfield(app.CustomPDFsettingsPanel, 'text');
            app.CustomPDF_StartValuesEditField.ValueChangedFcn = createCallbackFcn(app, @CustomPDF_StartValuesEditFieldValueChanged, true);
            app.CustomPDF_StartValuesEditField.Value = '[1]';
            app.LowerboundsEditFieldLabel = uilabel(app.CustomPDFsettingsPanel);
            app.LowerboundsEditFieldLabel.Text = 'Lower bounds';
            app.CustomPDF_LowerboundsEditField = uieditfield(app.CustomPDFsettingsPanel, 'text');
            app.CustomPDF_LowerboundsEditField.ValueChangedFcn = createCallbackFcn(app, @CustomPDF_LowerboundsEditFieldValueChanged, true);
            app.CustomPDF_LowerboundsEditField.Value = '[0.01]';
            app.UpperboundsEditFieldLabel = uilabel(app.CustomPDFsettingsPanel);
            app.UpperboundsEditFieldLabel.Text = 'Upper bounds';
            app.CustomPDF_UpperboundsEditField = uieditfield(app.CustomPDFsettingsPanel, 'text');
            app.CustomPDF_UpperboundsEditField.ValueChangedFcn = createCallbackFcn(app, @CustomPDF_UpperboundsEditFieldValueChanged, true);
            app.CustomPDF_UpperboundsEditField.Value = '[100]';
            app.ExpertTab = uitab(app.TabGroup);
            app.ExpertTab.Title = 'Expert';
            app.popEditFieldLabel = uilabel(app.ExpertTab);
            app.popEditFieldLabel.Text = '1 pop';
            app.popEditField = uieditfield(app.ExpertTab, 'text');
            app.popEditField.ValueChangedFcn = createCallbackFcn(app, @popEditFieldValueChanged, true);
            app.popEditField.Value = '[0.10-10]';
            app.popEditField_2Label = uilabel(app.ExpertTab);
            app.popEditField_2Label.Text = '2 pop';
            app.popEditField_2 = uieditfield(app.ExpertTab, 'text');
            app.popEditField_2.ValueChangedFcn = createCallbackFcn(app, @popEditField_2ValueChanged, true);
            app.popEditField_2.Value = '[0.5-2 5-20 1]';
            app.aDDAEditFieldLabel = uilabel(app.ExpertTab);
            app.aDDAEditFieldLabel.Text = 'aDDA';
            app.aDDAEditField = uieditfield(app.ExpertTab, 'text');
            app.aDDAEditField.ValueChangedFcn = createCallbackFcn(app, @aDDAEditFieldValueChanged, true);
            app.aDDAEditField.Value = '[50 50 1]';
            app.PerformEstimationFitCheckBox = uicheckbox(app.ExpertTab);
            app.PerformEstimationFitCheckBox.ValueChangedFcn = createCallbackFcn(app, @PerformEstimationFitCheckBoxValueChanged, true);
            app.PerformEstimationFitCheckBox.Tooltip = {'Whether or not a first, estimation fit should be used. It is recommendated that this is used, as it can prevent false fitting at local minima.'};
            app.PerformEstimationFitCheckBox.Text = 'Perform Estimation Fit';
            app.VerboseCheckBox = uicheckbox(app.ExpertTab);
            app.VerboseCheckBox.ValueChangedFcn = createCallbackFcn(app, @VerboseCheckBoxValueChanged, true);
            app.VerboseCheckBox.Tooltip = {'Check this for more elaborate feedback in the main MATLAB window.'};
            app.VerboseCheckBox.Text = 'Verbose';
            app.VerboseCheckBox.Value = true;
            app.NrofBGbinsEditFieldLabel = uilabel(app.ExpertTab);
            app.NrofBGbinsEditFieldLabel.Text = 'Nr of BG bins';
            app.NrofBGbinsEditField = uieditfield(app.ExpertTab, 'numeric');
            app.NrofBGbinsEditField.Limits = [10 10000];
            app.NrofBGbinsEditField.ValueChangedFcn = createCallbackFcn(app, @NrofBGbinsEditFieldValueChanged, true);
            app.NrofBGbinsEditField.Tooltip = {'How many background bins should be used to determine the background curve shape. Recommended to be somewhere between 100 and 500, but will not have a big influence.'};
            app.NrofBGbinsEditField.Value = 100;
            app.StartpointBGratioofmaxJDEditFieldLabel = uilabel(app.ExpertTab);
            app.StartpointBGratioofmaxJDEditFieldLabel.Text = 'Startpoint BG (ratio of max JD)';
            app.StartpointBGratioofmaxJDEditField = uieditfield(app.ExpertTab, 'numeric');
            app.StartpointBGratioofmaxJDEditField.Limits = [0.3 0.99];
            app.StartpointBGratioofmaxJDEditField.ValueChangedFcn = createCallbackFcn(app, @StartpointBGratioofmaxJDEditFieldValueChanged, true);
            app.StartpointBGratioofmaxJDEditField.Tooltip = {'At which jump distance the background-only portion is starting ONLY in the estimation fit. This is expressed as a ratio (0 to 1) of the maximum jump distance (Basic tab). The final actual jump distance should be higher than the largest jump distance in the signal.'};
            app.StartpointBGratioofmaxJDEditField.Value = 0.8;
            app.BGsubtractioninterpolationDropDownLabel = uilabel(app.ExpertTab);
            app.BGsubtractioninterpolationDropDownLabel.Text = 'BG subtraction interpolation';
            app.BGsubtractioninterpolationDropDown = uidropdown(app.ExpertTab);
            app.BGsubtractioninterpolationDropDown.Items = {'Linear', 'Logarithmic'};
            app.BGsubtractioninterpolationDropDown.ValueChangedFcn = createCallbackFcn(app, @BGsubtractioninterpolationDropDownValueChanged, true);
            app.BGsubtractioninterpolationDropDown.Value = 'Logarithmic';
            app.EditFieldLabel = uilabel(app.ExpertTab);
            app.EditFieldLabel.Text = '';
            app.lb_1popEditField = uieditfield(app.ExpertTab, 'text');
            app.lb_1popEditField.ValueChangedFcn = createCallbackFcn(app, @lb_1popEditFieldValueChanged, true);
            app.lb_1popEditField.Value = '[1e-4]';
            app.ub_1popEditField = uieditfield(app.ExpertTab, 'text');
            app.ub_1popEditField.ValueChangedFcn = createCallbackFcn(app, @ub_1popEditFieldValueChanged, true);
            app.ub_1popEditField.Value = '[1e4]';
            app.lb_2popEditField = uieditfield(app.ExpertTab, 'text');
            app.lb_2popEditField.ValueChangedFcn = createCallbackFcn(app, @lb_2popEditFieldValueChanged, true);
            app.lb_2popEditField.Value = '[1e-4 1e-4 1e-4]';
            app.ub_2popEditField = uieditfield(app.ExpertTab, 'text');
            app.ub_2popEditField.ValueChangedFcn = createCallbackFcn(app, @ub_2popEditFieldValueChanged, true);
            app.ub_2popEditField.Value = '[1e4 1e4 1e4]';
            app.lb_addaEditField = uieditfield(app.ExpertTab, 'text');
            app.lb_addaEditField.ValueChangedFcn = createCallbackFcn(app, @lb_addaEditFieldValueChanged, true);
            app.lb_addaEditField.Value = '[1e-4 1e-4 1e-4]';
            app.ub_addaEditField = uieditfield(app.ExpertTab, 'text');
            app.ub_addaEditField.ValueChangedFcn = createCallbackFcn(app, @ub_addaEditFieldValueChanged, true);
            app.ub_addaEditField.Value = '[1e4 1e4 1e4]';
            app.LowerLabel = uilabel(app.ExpertTab);
            app.LowerLabel.Text = 'Lower';
            app.UpperLabel = uilabel(app.ExpertTab);
            app.UpperLabel.Text = 'Upper';
            app.StartLabel = uilabel(app.ExpertTab);
            app.StartLabel.Text = 'Start';
            app.FittingconstraintsLabel = uilabel(app.ExpertTab);
            app.FittingconstraintsLabel.Text = 'Fitting constraints';
            app.OthersettingsLabel = uilabel(app.ExpertTab);
            app.OthersettingsLabel.Text = 'Other settings';
            app.StrobocorrectionButtonGroup = uibuttongroup(app.ExpertTab);
            app.StrobocorrectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @StrobocorrectionButtonGroupSelectionChanged, true);
            app.StrobocorrectionButtonGroup.Title = 'Strobo correction';
            app.NostrobocorrectionButton = uiradiobutton(app.StrobocorrectionButtonGroup);
            app.NostrobocorrectionButton.Text = 'No strobo correction';
            app.NostrobocorrectionButton.Value = true;
            app.FullframeexcitationButton = uiradiobutton(app.StrobocorrectionButtonGroup);
            app.FullframeexcitationButton.Text = 'Full-frame excitation';
            app.ExcitationpulseButton = uiradiobutton(app.StrobocorrectionButtonGroup);
            app.ExcitationpulseButton.Text = 'Excitation pulse:';
            app.ExcitationpulseDurationField = uieditfield(app.StrobocorrectionButtonGroup, 'text');
            app.ExcitationpulseDurationField.ValueChangedFcn = createCallbackFcn(app, @ExcitationpulseDurationFieldValueChanged, true);
            app.ExcitationpulseDurationField.Enable = 'off';
            app.msLabel = uilabel(app.StrobocorrectionButtonGroup);
            app.msLabel.Enable = 'off';
            app.msLabel.Text = 'ms';
            app.ExperimentalLabel = uilabel(app.ExpertTab);
            app.ExperimentalLabel.Visible = 'off';
            app.ExperimentalLabel.Text = 'Experimental';
            app.FittinglocalizationuncertaintyCheckBox = uicheckbox(app.ExpertTab);
            app.FittinglocalizationuncertaintyCheckBox.ValueChangedFcn = createCallbackFcn(app, @FittinglocalizationuncertaintyCheckBoxValueChanged, true);
            app.FittinglocalizationuncertaintyCheckBox.Visible = 'off';
            app.FittinglocalizationuncertaintyCheckBox.Tooltip = {'Fit the loc_unc rather than providing the user-set value. This only is feasible for nr dt bins > 3 and D < 0.5 um2/s. Only implemented for populations on Log BG.'};
            app.FittinglocalizationuncertaintyCheckBox.Text = 'Fitting localization uncertainty';
            app.Noisedensitylocum2frameLabel = uilabel(app.ExpertTab);
            app.Noisedensitylocum2frameLabel.Text = 'Noise density (loc/um2/frame)';
            app.SwiftParam_noiseDensityEditField = uieditfield(app.ExpertTab, 'numeric');
            app.SwiftParam_noiseDensityEditField.Limits = [0 Inf];
            app.SwiftParam_noiseDensityEditField.ValueChangedFcn = createCallbackFcn(app, @SwiftParam_noiseDensityEditFieldValueChanged, true);
            app.SwiftParam_noiseDensityEditField.Tooltip = {'At which jump distance the background-only portion is starting ONLY in the estimation fit. This is expressed as a ratio (0 to 1) of the maximum jump distance (Basic tab). The final actual jump distance should be higher than the largest jump distance in the signal.'};
            app.RuninfoTab = uitab(app.TabGroup);
            app.RuninfoTab.Title = 'Run info';
            app.TextArea = uitextarea(app.RuninfoTab);
            app.TextArea.Editable = 'off';
            app.LocFilterTab = uitab(app.TabGroup);
            app.LocFilterTab.Title = 'Loc Filter';
            app.Filter1EditFieldLabel = uilabel(app.LocFilterTab);
            app.Filter1EditFieldLabel.Text = 'Filter 1';
            app.Filter1EditField = uieditfield(app.LocFilterTab, 'text');
            app.Filter1EditField.ValueChangedFcn = createCallbackFcn(app, @Filter1EditFieldValueChanged, true);
            app.Filter1Sign = uidropdown(app.LocFilterTab);
            app.Filter1Sign.Items = {'<', '>', '<=', '>=', '=='};
            app.Filter1Sign.ValueChangedFcn = createCallbackFcn(app, @Filter1EditFieldValueChanged, true);
            app.Filter1Sign.Value = '<';
            app.Filter1ValEditField = uieditfield(app.LocFilterTab, 'numeric');
            app.Filter1ValEditField.ValueChangedFcn = createCallbackFcn(app, @Filter1EditFieldValueChanged, true);
            app.Filter2Sign = uidropdown(app.LocFilterTab);
            app.Filter2Sign.Items = {'<', '>', '<=', '>=', '=='};
            app.Filter2Sign.ValueChangedFcn = createCallbackFcn(app, @Filter1EditFieldValueChanged, true);
            app.Filter2Sign.Value = '<';
            app.Filter2ValEditField = uieditfield(app.LocFilterTab, 'numeric');
            app.Filter2ValEditField.ValueChangedFcn = createCallbackFcn(app, @Filter1EditFieldValueChanged, true);
            app.Filter2EditFieldLabel = uilabel(app.LocFilterTab);
            app.Filter2EditFieldLabel.Text = 'Filter 2';
            app.Filter2EditField = uieditfield(app.LocFilterTab, 'text');
            app.Filter2EditField.ValueChangedFcn = createCallbackFcn(app, @Filter1EditFieldValueChanged, true);
            app.Filter3Sign = uidropdown(app.LocFilterTab);
            app.Filter3Sign.Items = {'<', '>', '<=', '>=', '=='};
            app.Filter3Sign.ValueChangedFcn = createCallbackFcn(app, @Filter1EditFieldValueChanged, true);
            app.Filter3Sign.Value = '<';
            app.Filter3ValEditField = uieditfield(app.LocFilterTab, 'numeric');
            app.Filter3ValEditField.ValueChangedFcn = createCallbackFcn(app, @Filter1EditFieldValueChanged, true);
            app.Filter3EditFieldLabel = uilabel(app.LocFilterTab);
            app.Filter3EditFieldLabel.Text = 'Filter 3';
            app.Filter3EditField = uieditfield(app.LocFilterTab, 'text');
            app.Filter3EditField.ValueChangedFcn = createCallbackFcn(app, @Filter1EditFieldValueChanged, true);
            app.Filter4Sign = uidropdown(app.LocFilterTab);
            app.Filter4Sign.Items = {'<', '>', '<=', '>=', '=='};
            app.Filter4Sign.ValueChangedFcn = createCallbackFcn(app, @Filter1EditFieldValueChanged, true);
            app.Filter4Sign.Value = '<';
            app.Filter4ValEditField = uieditfield(app.LocFilterTab, 'numeric');
            app.Filter4ValEditField.ValueChangedFcn = createCallbackFcn(app, @Filter1EditFieldValueChanged, true);
            app.Filter4EditFieldLabel = uilabel(app.LocFilterTab);
            app.Filter4EditFieldLabel.Text = 'Filter 4';
            app.Filter4EditField = uieditfield(app.LocFilterTab, 'text');
            app.Filter4EditField.ValueChangedFcn = createCallbackFcn(app, @Filter1EditFieldValueChanged, true);
            app.Filter5Sign = uidropdown(app.LocFilterTab);
            app.Filter5Sign.Items = {'<', '>', '<=', '>=', '=='};
            app.Filter5Sign.ValueChangedFcn = createCallbackFcn(app, @Filter1EditFieldValueChanged, true);
            app.Filter5Sign.Value = '<';
            app.Filter5ValEditField = uieditfield(app.LocFilterTab, 'numeric');
            app.Filter5ValEditField.ValueChangedFcn = createCallbackFcn(app, @Filter1EditFieldValueChanged, true);
            app.Filter5EditFieldLabel = uilabel(app.LocFilterTab);
            app.Filter5EditFieldLabel.Text = 'Filter 5';
            app.Filter5EditField = uieditfield(app.LocFilterTab, 'text');
            app.Filter5EditField.ValueChangedFcn = createCallbackFcn(app, @Filter1EditFieldValueChanged, true);
            app.StorageVisualTab = uitab(app.TabGroup);
            app.StorageVisualTab.Title = 'Storage/Visual.';
            app.VisualisationscalingButtonGroup = uibuttongroup(app.StorageVisualTab);
            app.VisualisationscalingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @VisualisationscalingButtonGroupSelectionChanged, true);
            app.VisualisationscalingButtonGroup.Tooltip = {'Choose whether the visualisation (NOT the fitting) should be linear or logarthmic. AnaDDA fits will always be displayed logarithmic.'};
            app.VisualisationscalingButtonGroup.Title = 'Visualisation scaling';
            app.LinearVisButton = uiradiobutton(app.VisualisationscalingButtonGroup);
            app.LinearVisButton.Text = 'Linear';
            app.LogarithmicVisButton = uiradiobutton(app.VisualisationscalingButtonGroup);
            app.LogarithmicVisButton.Text = 'Logarithmic';
            app.LogarithmicVisButton.Value = true;
            app.AnalysedDataLocationEditFieldLabel = uilabel(app.StorageVisualTab);
            app.AnalysedDataLocationEditFieldLabel.Visible = 'off';
            app.AnalysedDataLocationEditFieldLabel.Text = 'Analysed Data Location';
            app.AnalysedDataLocationEditField = uieditfield(app.StorageVisualTab, 'text');
            app.AnalysedDataLocationEditField.Visible = 'off';
            app.VisualiseoldresultsButton = uibutton(app.StorageVisualTab, 'push');
            app.VisualiseoldresultsButton.ButtonPushedFcn = createCallbackFcn(app, @VisualiseoldresultsButtonPushed, true);
            app.VisualiseoldresultsButton.Text = 'Visualise old results';
            app.ShowlocalizationsButton = uibutton(app.StorageVisualTab, 'push');
            app.ShowlocalizationsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowlocalizationsButtonPushed, true);
            app.ShowlocalizationsButton.Visible = 'off';
            app.ShowlocalizationsButton.Tooltip = {'Show the localizations of the data selected above. If it''s a folder, the first file will be processed.'};
            app.ShowlocalizationsButton.Text = 'Show localizations';
            app.FileLocationFinder_vis = uibutton(app.StorageVisualTab, 'push');
            app.FileLocationFinder_vis.ButtonPushedFcn = createCallbackFcn(app, @FileLocationFinder_visButtonPushed, true);
            app.FileLocationFinder_vis.Visible = 'off';
            app.FileLocationFinder_vis.Tooltip = {'Select a file'};
            app.FileLocationFinder_vis.Text = 'File...';
            app.FolderLocationFinder_vis = uibutton(app.StorageVisualTab, 'push');
            app.FolderLocationFinder_vis.ButtonPushedFcn = createCallbackFcn(app, @FolderLocationFinder_visButtonPushed, true);
            app.FolderLocationFinder_vis.Visible = 'off';
            app.FolderLocationFinder_vis.Tooltip = {'Select a folder for batch-analysis'};
            app.FolderLocationFinder_vis.Text = 'Folder...';
            app.StorefinalfitimageCheckBox = uicheckbox(app.StorageVisualTab);
            app.StorefinalfitimageCheckBox.ValueChangedFcn = createCallbackFcn(app, @StorefinalfitimageCheckBoxValueChanged, true);
            app.StorefinalfitimageCheckBox.Text = 'Store final fit image';
            app.StorefinalfitimageCheckBox.Value = true;
            app.StoreintermediatefitimageCheckBox = uicheckbox(app.StorageVisualTab);
            app.StoreintermediatefitimageCheckBox.ValueChangedFcn = createCallbackFcn(app, @StoreintermediatefitimageCheckBoxValueChanged, true);
            app.StoreintermediatefitimageCheckBox.Text = 'Store intermediate fit image';
            app.StoreJDhistogramimageCheckBox = uicheckbox(app.StorageVisualTab);
            app.StoreJDhistogramimageCheckBox.ValueChangedFcn = createCallbackFcn(app, @StoreJDhistogramimageCheckBoxValueChanged, true);
            app.StoreJDhistogramimageCheckBox.Text = 'Store JD histogram image';
            app.StoreJDhistogramimageCheckBox.Value = true;
            app.StoreJSONfitdatarecommendedCheckBox = uicheckbox(app.StorageVisualTab);
            app.StoreJSONfitdatarecommendedCheckBox.ValueChangedFcn = createCallbackFcn(app, @StoreJSONfitdatarecommendedCheckBoxValueChanged, true);
            app.StoreJSONfitdatarecommendedCheckBox.Text = 'Store JSON fit data (recommended)';
            app.StoreJSONfitdatarecommendedCheckBox.Value = true;
            app.StorealldataNOTrecommendedverybigfilesizeCheckBox = uicheckbox(app.StorageVisualTab);
            app.StorealldataNOTrecommendedverybigfilesizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @StorealldataNOTrecommendedverybigfilesizeCheckBoxValueChanged, true);
            app.StorealldataNOTrecommendedverybigfilesizeCheckBox.Text = 'Store all data (NOT recommended; very big file size)';
            app.StoreMATfitdatarecommendedCheckBox = uicheckbox(app.StorageVisualTab);
            app.StoreMATfitdatarecommendedCheckBox.ValueChangedFcn = createCallbackFcn(app, @StoreMATfitdatarecommendedCheckBoxValueChanged, true);
            app.StoreMATfitdatarecommendedCheckBox.Text = 'Store MAT fit data (recommended)';
            app.StoreMATfitdatarecommendedCheckBox.Value = true;
            app.OpenstoragefolderButton = uibutton(app.StorageVisualTab, 'push');
            app.OpenstoragefolderButton.ButtonPushedFcn = createCallbackFcn(app, @OpenstoragefolderButtonPushed, true);
            app.OpenstoragefolderButton.Text = {'Open storage'; 'folder'};
            app.StoreswiftparametersCheckBox = uicheckbox(app.StorageVisualTab);
            app.StoreswiftparametersCheckBox.ValueChangedFcn = createCallbackFcn(app, @StoreswiftparametersCheckBoxValueChanged, true);
            app.StoreswiftparametersCheckBox.Text = 'Store swift parameters';
            app.VisualisealsoinseparatewindowCheckBox = uicheckbox(app.StorageVisualTab);
            app.VisualisealsoinseparatewindowCheckBox.ValueChangedFcn = createCallbackFcn(app, @VisualisealsoinseparatewindowCheckBoxValueChanged, true);
            app.VisualisealsoinseparatewindowCheckBox.Text = 'Visualise also in separate window';
            app.VisualisealsoinseparatewindowCheckBox.Value = true;
            app.StoreMHTparametersCheckBox = uicheckbox(app.StorageVisualTab);
            app.StoreMHTparametersCheckBox.ValueChangedFcn = createCallbackFcn(app, @StoreMHTparametersCheckBoxValueChanged, true);
            app.StoreMHTparametersCheckBox.Text = 'Store MHT parameters';
            app.DataLocationEditFieldLabel = uilabel(app.SettingsPanel);
            app.DataLocationEditFieldLabel.Text = 'Data Location';
            app.DataLocationEditField = uieditfield(app.SettingsPanel, 'text');
            app.DataLocationEditField.ValueChangedFcn = createCallbackFcn(app, @DataLocationEditFieldValueChanged, true);
            app.DataLocationEditField.Tooltip = {'Location of a single data-file (.mat with ''pos'' array or .csv ThunderSTORM output), or folder containing multiple input files.'};
            app.FileLocationFinder = uibutton(app.SettingsPanel, 'push');
            app.FileLocationFinder.ButtonPushedFcn = createCallbackFcn(app, @FileLocationFinderButtonPushed, true);
            app.FileLocationFinder.Tooltip = {'Select a file'};
            app.FileLocationFinder.Text = 'File...';
            app.FolderLocationFinder = uibutton(app.SettingsPanel, 'push');
            app.FolderLocationFinder.ButtonPushedFcn = createCallbackFcn(app, @FolderLocationFinderButtonPushed, true);
            app.FolderLocationFinder.Tooltip = {'Select a folder for batch-analysis'};
            app.FolderLocationFinder.Text = 'Folder...';
            app.RunTARDISButton = uibutton(app.LeftPanel, 'push');
            app.RunTARDISButton.ButtonPushedFcn = createCallbackFcn(app, @RunTARDISButtonPushed, true);
            app.RunTARDISButton.Tooltip = {'Trust me, I''m the Doctor.'};
            app.RunTARDISButton.Text = 'Run TARDIS';
            app.makeJDs = uibutton(app.LeftPanel, 'push');
            app.makeJDs.ButtonPushedFcn = createCallbackFcn(app, @makeJDsButtonPushed, true);
            app.makeJDs.Visible = 'off';
            app.makeJDs.Tooltip = {''};
            app.makeJDs.Text = 'JDs';
            app.ResetRunButton = uibutton(app.LeftPanel, 'push');
            app.ResetRunButton.ButtonPushedFcn = createCallbackFcn(app, @ResetRunButtonPushed, true);
            app.ResetRunButton.Text = 'ResetRunButton';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup2 = uitabgroup(app.RightPanel);
            app.FullresultsTab = uitab(app.TabGroup2);
            app.FullresultsTab.Title = 'Full results';
            app.RightBotPanel = uipanel(app.FullresultsTab);
            app.RightBotPanel.Title = 'TARDIS full results';
            app.ResultAxis = uiaxes(app.RightBotPanel);
            app.ResultAxis.XTick = [];
            app.ResultAxis.XTickLabel = '';
            app.ResultAxis.YTick = [];
            app.ResultAxis.Tag = 'ResultAxis';
            app.ResultsPanel = uipanel(app.FullresultsTab);
            app.ResultsPanel.Tag = 'ResultAxis';
            app.Hyperlink = uihyperlink(app.FullresultsTab);
            app.Hyperlink.URL = 'https://github.com/kjamartens/TARDIS-public';
            app.Hyperlink.Text = 'Instructions/Manual';
            app.IfyoufoundTARDISusefullpleaseciteusMartensetalLabel = uilabel(app.FullresultsTab);
            app.IfyoufoundTARDISusefullpleaseciteusMartensetalLabel.Text = 'If you found TARDIS useful, please cite us:';
            app.Hyperlink2 = uihyperlink(app.FullresultsTab);
            app.Hyperlink2.URL = 'https://rdcu.be/dv1sr';
            app.Hyperlink2.Text = 'Martens et al., "Temporal analysis of relative distances (TARDIS) is a robust, parameter-free alternative to single-particle tracking",                              (2024)';
            app.Hyperlink2_2 = uihyperlink(app.FullresultsTab);
            app.Hyperlink2_2.URL = 'https://rdcu.be/dv1sr';
            app.Hyperlink2_2.Text = 'Nature Methods';
            app.EstimationresultsTab = uitab(app.TabGroup2);
            app.EstimationresultsTab.Title = 'Estimation results';
            app.RightTopPanel = uipanel(app.EstimationresultsTab);
            app.RightTopPanel.Title = 'TARDIS estimation results';
            app.JumpdistancehistogramsTab = uitab(app.TabGroup2);
            app.JumpdistancehistogramsTab.Title = 'Jump distance histogram(s)';
            app.JDhistPanel = uipanel(app.JumpdistancehistogramsTab);
            app.JDhistPanel.Title = 'Jump distance histograms';
            app.LocalizationsTab = uitab(app.TabGroup2);
            app.LocalizationsTab.Title = 'Localizations';
            app.LocAxes = uiaxes(app.LocalizationsTab);
            title(app.LocAxes, 'Localizations')
            xlabel(app.LocAxes, 'x (m)')
            ylabel(app.LocAxes, 'y (m)')
            zlabel(app.LocAxes, 'Z')
            % Show the figure after all components are created
            app.TARDISTemporalAnalysisofRelativeDistancesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TARDIS_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TARDISTemporalAnalysisofRelativeDistancesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TARDISTemporalAnalysisofRelativeDistancesUIFigure)
        end
    end
end